classdef swrAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        swrAnalysisUIFigure          matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LFPTab                       matlab.ui.container.Tab
        fileNumLFPButtonGroup        matlab.ui.container.ButtonGroup
        SingleLFPButton              matlab.ui.control.RadioButton
        MultipleLFPButton            matlab.ui.control.RadioButton
        fileTypeButtonGroup          matlab.ui.container.ButtonGroup
        pClampButton                 matlab.ui.control.RadioButton
        ASCIIButton                  matlab.ui.control.RadioButton
        lfpFiltCheckBox              matlab.ui.control.CheckBox
        lfpLim1Label                 matlab.ui.control.Label
        lfpLim1Field                 matlab.ui.control.NumericEditField
        lfpLim2Label                 matlab.ui.control.Label
        lfpLim2Field                 matlab.ui.control.NumericEditField
        SamplingFrequencyLabel       matlab.ui.control.Label
        FsField                      matlab.ui.control.NumericEditField
        LFPChannelLabel              matlab.ui.control.Label
        lpfChannelField              matlab.ui.control.NumericEditField
        DownsampleFactorLabel        matlab.ui.control.Label
        dsFactorField                matlab.ui.control.NumericEditField
        spectCheckBox                matlab.ui.control.CheckBox
        lfpFiltLamp                  matlab.ui.control.Lamp
        spectLamp                    matlab.ui.control.Lamp
        analyzeLFPButton             matlab.ui.control.Button
        plotLFPButton                matlab.ui.control.Button
        reanalyzeLFPButton           matlab.ui.control.Button
        HzLabel                      matlab.ui.control.Label
        SWRDetectionPanel            matlab.ui.container.Panel
        swFiltCheckBox               matlab.ui.control.CheckBox
        rFiltCheckBox                matlab.ui.control.CheckBox
        swFiltLamp                   matlab.ui.control.Lamp
        rFiltLamp                    matlab.ui.control.Lamp
        expSWREvCheckbox             matlab.ui.control.CheckBox
        msLabel_2                    matlab.ui.control.Label
        msLabel_3                    matlab.ui.control.Label
        peakDetectSWRCheckbox        matlab.ui.control.CheckBox
        rmsSWRCheckbox               matlab.ui.control.CheckBox
        HzLabel_6                    matlab.ui.control.Label
        HzLabel_7                    matlab.ui.control.Label
        rmsSWRLamp                   matlab.ui.control.Lamp
        peakDetectSWRLamp            matlab.ui.control.Lamp
        expSWRDataCheckbox           matlab.ui.control.CheckBox
        swLim1Label                  matlab.ui.control.Label
        swLim1Field                  matlab.ui.control.NumericEditField
        swLim2Label                  matlab.ui.control.Label
        swLim2Field                  matlab.ui.control.NumericEditField
        rLim1Label                   matlab.ui.control.Label
        rLim1Field                   matlab.ui.control.NumericEditField
        rLim2Label                   matlab.ui.control.Label
        rLim2Field                   matlab.ui.control.NumericEditField
        BLQuantileLabel              matlab.ui.control.Label
        baseQuantLFPField            matlab.ui.control.NumericEditField
        RMSPeriodLabel               matlab.ui.control.Label
        rmsPeriodField               matlab.ui.control.NumericEditField
        SWRStandardWindowLabel       matlab.ui.control.Label
        swrWindowField               matlab.ui.control.NumericEditField
        MinEventDifferenceLabel      matlab.ui.control.Label
        rmsMinEvDiffField            matlab.ui.control.NumericEditField
        SDMultipleThresholdLabel_2   matlab.ui.control.Label
        sdMultLFPField               matlab.ui.control.NumericEditField
        expLFPAveCheckbox            matlab.ui.control.CheckBox
        OtherFiltersPanel            matlab.ui.container.Panel
        thetaFiltCheckBox            matlab.ui.control.CheckBox
        gammaFiltCheckBox            matlab.ui.control.CheckBox
        thetaFiltLamp                matlab.ui.control.Lamp
        gammaFiltLamp                matlab.ui.control.Lamp
        thetaLim1Label               matlab.ui.control.Label
        thetaLim1Field               matlab.ui.control.NumericEditField
        thetaLim2Label               matlab.ui.control.Label
        thetaLim2Field               matlab.ui.control.NumericEditField
        gammaLim1Label               matlab.ui.control.Label
        gammaLim1Field               matlab.ui.control.NumericEditField
        gammaLim2Label               matlab.ui.control.Label
        gammaLim2Field               matlab.ui.control.NumericEditField
        hgammaFiltCheckBox           matlab.ui.control.CheckBox
        hgammaFiltLamp               matlab.ui.control.Lamp
        gammaLim1Label_2             matlab.ui.control.Label
        hgammaLim1Field              matlab.ui.control.NumericEditField
        gammaLim2Label_2             matlab.ui.control.Label
        hgammaLim2Field              matlab.ui.control.NumericEditField
        betaFiltCheckBox             matlab.ui.control.CheckBox
        betaFiltLamp                 matlab.ui.control.Lamp
        thetaLim1Label_2             matlab.ui.control.Label
        betaLim1Field                matlab.ui.control.NumericEditField
        thetaLim2Label_2             matlab.ui.control.Label
        betaLim2Field                matlab.ui.control.NumericEditField
        HzLabel_2                    matlab.ui.control.Label
        HzLabel_3                    matlab.ui.control.Label
        HzLabel_4                    matlab.ui.control.Label
        HzLabel_5                    matlab.ui.control.Label
        fRFiltCheckBox               matlab.ui.control.CheckBox
        fRFiltLamp                   matlab.ui.control.Lamp
        HzLabel_14                   matlab.ui.control.Label
        gammaLim1Label_6             matlab.ui.control.Label
        fRLim1Field                  matlab.ui.control.NumericEditField
        gammaLim2Label_6             matlab.ui.control.Label
        fRLim2Field                  matlab.ui.control.NumericEditField
        gammaLim1Label_3             matlab.ui.control.Label
        spectLim1Field               matlab.ui.control.NumericEditField
        gammaLim2Label_3             matlab.ui.control.Label
        spectLim2Field               matlab.ui.control.NumericEditField
        HzLabel_8                    matlab.ui.control.Label
        swrDetectCheckBox            matlab.ui.control.CheckBox
        cellCheckBox                 matlab.ui.control.CheckBox
        CellChannelLabel             matlab.ui.control.Label
        cellChannelField             matlab.ui.control.NumericEditField
        cellChannelLamp              matlab.ui.control.Lamp
        lfpChannelLamp               matlab.ui.control.Lamp
        SWRReviewPanel               matlab.ui.container.Panel
        rejectSWREvButton            matlab.ui.control.Button
        prevSWREvButton              matlab.ui.control.Button
        nextSWREvButton              matlab.ui.control.Button
        selSWREvLabel                matlab.ui.control.Label
        textLabel                    matlab.ui.control.Label
        nSWREvLabel                  matlab.ui.control.Label
        notchFiltCheckBox            matlab.ui.control.CheckBox
        notchFiltFreqField           matlab.ui.control.NumericEditField
        HzLabel_16                   matlab.ui.control.Label
        importStimCheckBox           matlab.ui.control.CheckBox
        swrTypeButtonGroup           matlab.ui.container.ButtonGroup
        SWAndRippleButton            matlab.ui.control.RadioButton
        SWOnlyButton                 matlab.ui.control.RadioButton
        RippleOnlyButton             matlab.ui.control.RadioButton
        spkTab                       matlab.ui.container.Tab
        fileNumSpkButtonGroup        matlab.ui.container.ButtonGroup
        SingleSpkButton              matlab.ui.control.RadioButton
        MultipleSpkButton            matlab.ui.control.RadioButton
        analyzeSpkButton             matlab.ui.control.Button
        plotSpkButton                matlab.ui.control.Button
        importSpkCheckBox            matlab.ui.control.CheckBox
        swrSpkButtonGroup            matlab.ui.container.ButtonGroup
        useSWRDurationSpkButton      matlab.ui.control.RadioButton
        useSWRWindowSpkButton        matlab.ui.control.RadioButton
        calcEvMatrixSpkCheckBox      matlab.ui.control.CheckBox
        parseSpkCheckBox             matlab.ui.control.CheckBox
        useSWRDurationSpkLamp        matlab.ui.control.Lamp
        useSWRWindowSpkLamp          matlab.ui.control.Lamp
        parseSpkLamp                 matlab.ui.control.Lamp
        calcEvMatrixSpkLamp          matlab.ui.control.Lamp
        expSWRSpkCheckbox            matlab.ui.control.CheckBox
        importSpkLamp                matlab.ui.control.Lamp
        expSpkEvCheckBox             matlab.ui.control.CheckBox
        expBstEvCheckBox             matlab.ui.control.CheckBox
        reanalyzeSpkButton           matlab.ui.control.Button
        CAAnalysisTypeButtonGroup    matlab.ui.container.ButtonGroup
        swrSpkButton                 matlab.ui.control.RadioButton
        lfpSpkButton                 matlab.ui.control.RadioButton
        swrBstCheckBox               matlab.ui.control.CheckBox
        swrBstLamp                   matlab.ui.control.Lamp
        swrSpkLamp                   matlab.ui.control.Lamp
        lfpSpkLamp                   matlab.ui.control.Lamp
        PSCTab                       matlab.ui.container.Tab
        fileNumPSCButtonGroup        matlab.ui.container.ButtonGroup
        SinglePSCButton              matlab.ui.control.RadioButton
        MultiplePSCButton            matlab.ui.control.RadioButton
        analyzePSCButton             matlab.ui.control.Button
        plotPSCButton                matlab.ui.control.Button
        importPSCCheckBox            matlab.ui.control.CheckBox
        swrPSCCheckBox               matlab.ui.control.CheckBox
        swrPSCButtonGroup            matlab.ui.container.ButtonGroup
        useSWRDurationPSCButton      matlab.ui.control.RadioButton
        useSWRWindowPSCButton        matlab.ui.control.RadioButton
        calcEvMatrixPSCCheckBox      matlab.ui.control.CheckBox
        parsePSCCheckBox             matlab.ui.control.CheckBox
        useSWRDurationPSCLamp        matlab.ui.control.Lamp
        useSWRWindowPSCLamp          matlab.ui.control.Lamp
        parsePSCLamp                 matlab.ui.control.Lamp
        calcEvMatrixPSCLamp          matlab.ui.control.Lamp
        expSWRPSCCheckbox            matlab.ui.control.CheckBox
        importPSCLamp                matlab.ui.control.Lamp
        swrPSCLamp                   matlab.ui.control.Lamp
        expPSCEvCheckBox             matlab.ui.control.CheckBox
        reanalyzePSCButton           matlab.ui.control.Button
        FiltersPSCPanel              matlab.ui.container.Panel
        gammaFiltPSCCheckBox         matlab.ui.control.CheckBox
        rFiltPSCCheckBox             matlab.ui.control.CheckBox
        gammaFiltPSCLamp             matlab.ui.control.Lamp
        rFiltPSCLamp                 matlab.ui.control.Lamp
        thetaLim1Label_3             matlab.ui.control.Label
        gammaLim1PSCField            matlab.ui.control.NumericEditField
        thetaLim2Label_3             matlab.ui.control.Label
        gammaLim2PSCField            matlab.ui.control.NumericEditField
        gammaLim1Label_4             matlab.ui.control.Label
        rLim1PSCField                matlab.ui.control.NumericEditField
        gammaLim2Label_4             matlab.ui.control.Label
        rLim2PSCField                matlab.ui.control.NumericEditField
        HzLabel_10                   matlab.ui.control.Label
        HzLabel_12                   matlab.ui.control.Label
        spectPSCCheckBox             matlab.ui.control.CheckBox
        spectPSCLamp                 matlab.ui.control.Lamp
        HzLabel_15                   matlab.ui.control.Label
        gammaLim1Label_7             matlab.ui.control.Label
        spectLim1PSCField            matlab.ui.control.NumericEditField
        gammaLim2Label_7             matlab.ui.control.Label
        spectLim2PSCField            matlab.ui.control.NumericEditField
        pscEventPolarityButtonGroup  matlab.ui.container.ButtonGroup
        pscPosPolarityButton         matlab.ui.control.RadioButton
        pscNegPolarityButton         matlab.ui.control.RadioButton
        PSCPolarityLabel             matlab.ui.control.Label
        swrPSQCheckBox               matlab.ui.control.CheckBox
        CaTab                        matlab.ui.container.Tab
        fileNumCaButtonGroup         matlab.ui.container.ButtonGroup
        SingleButtonCa               matlab.ui.control.RadioButton
        MultipleButtonCa             matlab.ui.control.RadioButton
        SDThresholdLabel             matlab.ui.control.Label
        sdMultCaField                matlab.ui.control.NumericEditField
        SamplingIntervalLabel        matlab.ui.control.Label
        samplingIntCaField           matlab.ui.control.NumericEditField
        msLabel                      matlab.ui.control.Label
        analyzeCaButton              matlab.ui.control.Button
        reanalyzeCaButton            matlab.ui.control.Button
        SDLabel_2                    matlab.ui.control.Label
        expCaCheckbox                matlab.ui.control.CheckBox
        interpCheckbox               matlab.ui.control.CheckBox
        peakDetectCaCheckbox         matlab.ui.control.CheckBox
        interpCaLamp                 matlab.ui.control.Lamp
        peakDetectCaLamp             matlab.ui.control.Lamp
        plotCaButton                 matlab.ui.control.Button
        swrCaButtonGroup             matlab.ui.container.ButtonGroup
        useSWRDurationCaButton       matlab.ui.control.RadioButton
        useSWRWindowCaButton         matlab.ui.control.RadioButton
        expSWRCaCheckbox             matlab.ui.control.CheckBox
        consistentThreshCheckBox     matlab.ui.control.CheckBox
        baseDetectCaButtonGroup      matlab.ui.container.ButtonGroup
        baseDetectCaButton1          matlab.ui.control.RadioButton
        baseDetectCaButton2          matlab.ui.control.RadioButton
        baseQuantCaField             matlab.ui.control.NumericEditField
        MinDiffbetweenPeaksLabel     matlab.ui.control.Label
        pkDiffMinField               matlab.ui.control.NumericEditField
        FFLabel                      matlab.ui.control.Label
        PeakSimilarityLimitLabel     matlab.ui.control.Label
        pkSimLimField                matlab.ui.control.NumericEditField
        KurtosisMinLabel             matlab.ui.control.Label
        kurtosisMinField             matlab.ui.control.NumericEditField
        KurtosisMaxLabel             matlab.ui.control.Label
        kurtosisMaxField             matlab.ui.control.NumericEditField
        ExclusionQuantileLabel       matlab.ui.control.Label
        excludeQuantField            matlab.ui.control.NumericEditField
        plotFitHistoCheckBox         matlab.ui.control.CheckBox
        baseCorrectCaButtonGroup     matlab.ui.container.ButtonGroup
        baseCorrectCaButton1         matlab.ui.control.RadioButton
        baseCorrectCaButton2         matlab.ui.control.RadioButton
        CaLim1Field                  matlab.ui.control.NumericEditField
        CaLim2Field                  matlab.ui.control.NumericEditField
        HzLabel_9                    matlab.ui.control.Label
        toLabel                      matlab.ui.control.Label
        FilterOrderLabel             matlab.ui.control.Label
        CaFiltOrderField             matlab.ui.control.NumericEditField
        FilterAlphaLabel             matlab.ui.control.Label
        CaFiltAlphaField             matlab.ui.control.NumericEditField
        SmoothFactorLabel            matlab.ui.control.Label
        smoothFactorField            matlab.ui.control.NumericEditField
        SkipdetectionforfirstLabel   matlab.ui.control.Label
        skipDetectLimField           matlab.ui.control.NumericEditField
        sLabel                       matlab.ui.control.Label
        baseDetectCaCheckbox         matlab.ui.control.CheckBox
        baseCorrectCaLamp            matlab.ui.control.Lamp
        baseDetectCaLamp             matlab.ui.control.Lamp
        stimCaPanel                  matlab.ui.container.Panel
        msafterstimulationpeakLabel  matlab.ui.control.Label
        fromLabel                    matlab.ui.control.Label
        stimCaLim1Field              matlab.ui.control.NumericEditField
        toLabel_2                    matlab.ui.control.Label
        stimCaLim2Field              matlab.ui.control.NumericEditField
        expStimCaCheckbox            matlab.ui.control.CheckBox
        swrCaCheckbox                matlab.ui.control.CheckBox
        swrCaLamp                    matlab.ui.control.Lamp
        stimCaCheckBox               matlab.ui.control.CheckBox
        sdBaseFactorLabel            matlab.ui.control.Label
        sdBaseFactorCaField          matlab.ui.control.NumericEditField
        alignOptionGroup             matlab.ui.container.ButtonGroup
        alignOptionStartButton       matlab.ui.control.RadioButton
        alignOptionEndButton         matlab.ui.control.RadioButton
        baseCorrectCaCheckbox        matlab.ui.control.CheckBox
        cellTypePanel                matlab.ui.container.Panel
        Type1EditFieldLabel          matlab.ui.control.Label
        cellType1EditField           matlab.ui.control.EditField
        Type2EditFieldLabel          matlab.ui.control.Label
        cellType2EditField           matlab.ui.control.EditField
        cellTypeOptionCheckbox       matlab.ui.control.CheckBox
        ResultsTab                   matlab.ui.container.Tab
        resultTree                   matlab.ui.container.Tree
        TypeLabel                    matlab.ui.control.Label
        varTypeTextArea              matlab.ui.control.TextArea
        varValueTable                matlab.ui.control.Table
        exportEventsButton           matlab.ui.control.Button
        dataFile1Loaded              matlab.ui.control.Label
        dataFile2Loaded              matlab.ui.control.Label
        openFile2Button              matlab.ui.control.Button
        closeFileButton              matlab.ui.control.Button
        openFileButton               matlab.ui.control.Button
        openPrevFileButton           matlab.ui.control.Button
        openNextFileButton           matlab.ui.control.Button
        saveFileButton               matlab.ui.control.Button
        openFile3Button              matlab.ui.control.Button
        dataFile3Loaded              matlab.ui.control.Label
    end

  
  properties (Access = public)
    data       % structure containing all data to be saved
    data2      % structure containing 2nd data set for comparisons
    data3      % structure containing 3rd data set for comparisons
    hand       % handle structure for figures
    param      % structure for all parameters entered in UI for analysis/plotting
    selNode    % selected node in results UI tree
    selVar     % selected variable in results UI tree
    selData    % currently selected data structure (data or data2)
    selEvent   % currently selected SWR event
    parentPath % path of folder containing opened datafile (only used for data, not second data2 file)
    dataFiles  % cell array containing all *.mat files in parentPath
    
  end
  
  methods (Access = private)
    
    function updateUITree(app, dataStruct)
      varNames1    = fieldnames(dataStruct);
      dataFileCat  = uitreenode(app.resultTree,'Text', dataStruct.saveName, 'NodeData',[]);
      for i1 = 1:length(varNames1)
        dataCat1 = uitreenode(dataFileCat,'Text',varNames1{i1},'NodeData', []);
        if isstruct(dataStruct.(varNames1{i1}))
          varNames2 = fieldnames(dataStruct.(varNames1{i1}));
          for i2 = 1:length(varNames2)
            dataCat2 = uitreenode(dataCat1,'Text',varNames2{i2},'NodeData', []);
            if isstruct(dataStruct.(varNames1{i1}).(varNames2{i2}))
              varNames3 = fieldnames(dataStruct.(varNames1{i1}).(varNames2{i2}));
              for i3 = 1:length(varNames3)
                uitreenode(dataCat2,'Text',varNames3{i3},'NodeData', []);
              end
            end
          end
        end
      end
    end
    
    function updateDataOptions(app)
        
      % LFP Options
      if isfield(app.selData, 'LFP')
        app.lfpChannelLamp.Color = [0 1 0];  
        if isfield(app.selData.LFP, 'tSeries')
          app.lfpFiltLamp.Color = [0 1 0];
          app.plotLFPButton.Enable = 1;
        else
          app.lfpFiltLamp.Color = [1 0 0];
          app.plotLFPButton.Enable = 0;
        end
        
        % Spectrogram Options
        if isfield(app.selData.LFP, 'spect')
          app.spectLamp.Color = [0 1 0];
        end
        
      else
        app.lfpChannelLamp.Color = [1 0 0];
        app.lfpFiltLamp.Color = [1 0 0];
        app.spectLamp.Color = [1 0 0];
        app.plotLFPButton.Enable = 0;
      end
      
      % Cell Channel Options
      if isfield(app.selData, 'C')
        app.cellChannelLamp.Color = [0 1 0];
        if (isfield(app.selData.C, 'spike') && isfield(app.selData.SWR, 'spike'))
          app.plotSpkButton.Enable = 1;
        else
          app.plotSpkButton.Enable = 0;
        end
        
        if (isfield(app.selData.C, 'PSC') && isfield(app.selData.SWR, 'PSC'))
          app.plotPSCButton.Enable = 1;
        else
          app.plotPSCButton.Enable = 0;
        end
                
      else
        app.cellChannelLamp.Color = [1 0 0];
        app.plotSpkButton.Enable = 0;
      end
      
      % SW Options
      if isfield(app.selData, 'SW')
        if isfield(app.selData.SW, 'tSeries')
          app.swFiltLamp.Color = [0 1 0];
        else
          app.swFiltLamp.Color = [1 0 0];
        end
      else
        app.swFiltLamp.Color = [1 0 0];
      end
      
      % Ripple Options
      if isfield(app.selData, 'R')
        if isfield(app.selData.R, 'tSeries')
          app.rFiltLamp.Color = [0 1 0];
        else
          app.rFiltLamp.Color = [1 0 0];
        end
      else
        app.rFiltLamp.Color = [1 0 0];
      end
      
      % SWR - RMS Options
      if isfield(app.selData, 'SW') && isfield(app.selData, 'R')
        if isfield(app.selData.SW, 'RMS') && isfield(app.selData.R, 'RMS')
          app.rmsSWRLamp.Color = [0 1 0];
        else
          app.rmsSWRLamp.Color = [1 0 0];
        end
      else
        app.rmsSWRLamp.Color = [1 0 0];
      end

      % SWR - Peak Detect Options
      if isfield(app.selData, 'SWR')
        if isfield(app.selData.SWR, 'evStatus')
          app.peakDetectSWRLamp.Color = [0 1 0];
        else
          app.peakDetectSWRLamp.Color = [1 0 0];
        end
      else
        app.peakDetectSWRLamp.Color = [1 0 0];
      end
      
      % Theta Options
      if isfield(app.selData, 'theta')
        if isfield(app.selData.theta, 'tSeries')
          app.thetaFiltLamp.Color = [0 1 0];
        else
          app.thetaFiltLamp.Color = [1 0 0];
        end
      else
        app.thetaFiltLamp.Color = [1 0 0];
      end

      % Beta Options
      if isfield(app.selData, 'beta')
        if isfield(app.selData.beta, 'tSeries')
          app.betaFiltLamp.Color = [0 1 0];
        else
          app.betaFiltLamp.Color = [1 0 0];
        end
      else
        app.betaFiltLamp.Color = [1 0 0];
      end
      
      % Gamma Options
      if isfield(app.selData, 'gamma')
        if isfield(app.selData.gamma, 'tSeries')
          app.gammaFiltLamp.Color = [0 1 0];
        else
          app.gammaFiltLamp.Color = [1 0 0];
        end
      else
        app.gammaFiltLamp.Color = [1 0 0];
      end
      
      % High Gamma Options
      if isfield(app.selData, 'hgamma')
        if isfield(app.selData.hgamma, 'tSeries')
          app.hgammaFiltLamp.Color = [0 1 0];
        else
          app.hgammaFiltLamp.Color = [1 0 0];
        end
      else
        app.hgammaFiltLamp.Color = [1 0 0];
      end

      % Cell-attached Options
      if isfield(app.selData, 'C')
        if isfield(app.selData.C, 'spike')
          
          % Import Spike Options
          if isfield(app.selData.C.spike, 'evStatus')
            app.importSpkLamp.Color = [0 1 0];
          else
            app.importSpkLamp.Color = [1 0 0];
          end
          
          % Process spikes options
          if isfield(app.selData.C.spike, 'nEventsA')
            app.swrSpkLamp.Color = [0 1 0];
            if isfield(app.selData.SWR, 'evStatusStand')
              app.useSWRDurationSpkLamp.Color = [1 0 0];  
              app.useSWRWindowSpkLamp.Color   = [0 1 0];
            else
              app.useSWRDurationSpkLamp.Color = [0 1 0];  
              app.useSWRWindowSpkLamp.Color   = [1 0 0];   
            end
          else
            app.swrSpkLamp.Color = [1 0 0];
            app.useSWRDurationSpkLamp.Color = [1 0 0];
            app.useSWRWindowSpkLamp.Color   = [1 0 0];
          end
          
          % Calculate event matrix options
          if isfield(app.selData.C.spike, 'swrMatrix')
            app.calcEvMatrixSpkLamp.Color = [0 1 0];
          else
            app.calcEvMatrixSpkLamp.Color = [1 0 0];
          end
          
        else
          app.importSpkLamp.Color         = [1 0 0]; 
          app.swrSpkLamp.Color           = [1 0 0]; 
          app.useSWRDurationSpkLamp.Color = [1 0 0];
          app.useSWRWindowSpkLamp.Color   = [1 0 0];
          app.calcEvMatrixSpkLamp.Color   = [1 0 0];
        end
      else
        app.importSpkLamp.Color         = [1 0 0];
        app.swrSpkLamp.Color           = [1 0 0];
        app.useSWRDurationSpkLamp.Color = [1 0 0];
        app.useSWRWindowSpkLamp.Color   = [1 0 0];
        app.calcEvMatrixSpkLamp.Color   = [1 0 0];
      end
      
      % Parse spikes options
      if isfield(app.selData, 'C')
        if isfield(app.selData.C, 'SWR')
          if isfield(app.selData.C.SWR, 'spike')
            app.parseSpkLamp.Color = [0 1 0];
          else
            app.parseSpkLamp.Color = [1 0 0];
          end
        else
          app.parseSpkLamp.Color = [1 0 0];
        end
      else
        app.parseSpkLamp.Color = [1 0 0];
      end

      % Burst options
      if isfield(app.selData, 'C')
        if isfield(app.selData.C, 'burst')
          if isfield(app.selData.C.burst, 'evStatus')
            app.swrBstLamp.Color = [0 1 0];
          else
            app.swrBstLamp.Color = [1 0 0];
          end
        else
          app.swrBstLamp.Color = [1 0 0];
        end
      else
        app.swrBstLamp.Color = [1 0 0];
      end
      
      % Whole-cell Options
      if isfield(app.selData, 'C')
        if isfield(app.selData.C, 'PSC')
          
          % Import PSC Options
          if isfield(app.selData.C.PSC, 'evStatusPeak')
            app.importPSCLamp.Color = [0 1 0];
          else
            app.importPSCLamp.Color = [1 0 0];
          end
          
          % Process PSCs options
          if isfield(app.selData.C.PSC, 'nEventsA')
            app.swrPSCLamp.Color = [0 1 0];
            if isfield(app.selData.SWR, 'evStatusStand')
              app.useSWRDurationPSCLamp.Color = [1 0 0];  
              app.useSWRWindowPSCLamp.Color   = [0 1 0];
            else
              app.useSWRDurationPSCLamp.Color = [0 1 0];  
              app.useSWRWindowPSCLamp.Color   = [1 0 0];   
            end
          else
            app.swrPSCLamp.Color = [1 0 0];
            app.useSWRDurationPSCLamp.Color = [1 0 0];
            app.useSWRWindowPSCLamp.Color   = [1 0 0];
          end
          
          % Calculate event matrix options
          if isfield(app.selData.C.PSC, 'swrMatrix')
            app.calcEvMatrixPSCLamp.Color = [0 1 0];
          else
            app.calcEvMatrixPSCLamp.Color = [1 0 0];
          end
          
        else
          app.importPSCLamp.Color         = [1 0 0]; 
          app.swrPSCLamp.Color            = [1 0 0]; 
          app.useSWRDurationPSCLamp.Color = [1 0 0];
          app.useSWRWindowPSCLamp.Color   = [1 0 0];
          app.calcEvMatrixPSCLamp.Color   = [1 0 0];
        end
      else
        app.importPSCLamp.Color         = [1 0 0];
        app.swrPSCLamp.Color            = [1 0 0];
        app.useSWRDurationPSCLamp.Color = [1 0 0];
        app.useSWRWindowPSCLamp.Color   = [1 0 0];
        app.calcEvMatrixPSCLamp.Color   = [1 0 0];
      end
      
      % Parse PSCs options
      if isfield(app.selData, 'C')
        if isfield(app.selData.C, 'SWR')
          if isfield(app.selData.C.SWR, 'PSC')
            app.parsePSCLamp.Color = [0 1 0];
          else
            app.parsePSCLamp.Color = [1 0 0];
          end
        else
          app.parsePSCLamp.Color = [1 0 0];
        end
      else
        app.parsePSCLamp.Color = [1 0 0];
      end
      

      % Ca - baseline correction Options
      if isfield(app.selData, 'Ca')
        if isfield(app.selData.Ca, 'tSeriesF')
          app.baseCorrectCaLamp.Color = [0 1 0];
        else
          app.baseCorrectCaLamp.Color = [1 0 0];
        end
      else
        app.baseCorrectCaLamp.Color = [1 0 0];
      end
      
      % Ca - interpolation Options
      if isfield(app.selData, 'Ca')
        if isfield(app.selData.Ca, 'tSeriesI')
          app.interpCaLamp.Color = [0 1 0];
        else
          app.interpCaLamp.Color = [1 0 0];
        end
      else
        app.interpCaLamp.Color = [1 0 0];
      end
      
      % Ca - peak detection Options
      if isfield(app.selData, 'Ca')
        if (isfield(app.selData.Ca, 'evStatus'))
          app.peakDetectCaLamp.Color = [0 1 0];
        else
          app.peakDetectCaLamp.Color = [1 0 0];
        end
      else
        app.peakDetectCaLamp.Color = [1 0 0];
      end

      % Ca - baseline detection Options
      if isfield(app.selData, 'Ca')
        if (isfield(app.selData.Ca, 'baseSD'))
          app.baseDetectCaLamp.Color = [0 1 0];
        else
          app.baseDetectCaLamp.Color = [1 0 0];
        end
      else
        app.baseDetectCaLamp.Color = [1 0 0];
      end
      
      % Ca - SWR Match Options
      if isfield(app.selData, 'Ca')
        if (isfield(app.selData.Ca, 'SWR'))
          app.swrCaLamp.Color = [0 1 0];
        else
          app.swrCaLamp.Color = [1 0 0];
        end
      else
        app.swrCaLamp.Color = [1 0 0];
      end
      
      % Plot Ca Options
      if isfield(app.selData, 'Ca')
        app.plotCaButton.Enable = 1;
      else
        app.plotCaButton.Enable = 0;
      end
      
    end
    
    function updateParamLFP(app)
      app.param = [];
      
      if app.SingleLFPButton.Value
        app.param.fileNum = 1;
      elseif app.MultipleLFPButton.Value
        app.param.fileNum = 2;
      end
      
      if app.pClampButton.Value
        app.param.fileType = 1;
      elseif app.ASCIIButton.Value
        app.param.fileType = 2;
      end
      
      app.param.Fs               = app.FsField.Value;
      app.param.dsFactor         = app.dsFactorField.Value;
      app.param.lfpChannel       = app.lpfChannelField.Value;
      app.param.cellOption       = app.cellCheckBox.Value;
      app.param.cellChannel      = app.cellChannelField.Value;
      app.param.notchOption      = app.notchFiltCheckBox.Value;
      app.param.notchFreq        = app.notchFiltFreqField.Value;
      app.param.lfpOption        = app.lfpFiltCheckBox.Value;
      app.param.lfpLim1          = app.lfpLim1Field.Value;
      app.param.lfpLim2          = app.lfpLim2Field.Value;
      app.param.swrOption        = app.swrDetectCheckBox.Value;
      app.param.swOption         = app.swFiltCheckBox.Value;
      app.param.swLim1           = app.swLim1Field.Value;
      app.param.swLim2           = app.swLim2Field.Value;
      app.param.rOption          = app.rFiltCheckBox.Value;
      app.param.rLim1            = app.rLim1Field.Value;
      app.param.rLim2            = app.rLim2Field.Value;
      app.param.rmsOption        = app.rmsSWRCheckbox.Value;
      app.param.rmsMinEvDiff     = app.rmsMinEvDiffField.Value;
      app.param.rmsPeriod        = app.rmsPeriodField.Value;
      app.param.peakDetectOption = app.peakDetectSWRCheckbox.Value;
      app.param.sdMult           = app.sdMultLFPField.Value;
      
      if app.SWAndRippleButton.Value
        app.param.swrType = 1;
      elseif app.SWOnlyButton.Value
        app.param.swrType = 2;
      elseif app.RippleOnlyButton.Value
        app.param.swrType = 3;
      end
      
      app.param.baseQuant        = app.baseQuantLFPField.Value;
      app.param.swrWindow        = app.swrWindowField.Value;
      app.param.expSWREvOption   = app.expSWREvCheckbox.Value;
      app.param.expLFPAveOption  = app.expLFPAveCheckbox.Value;
      app.param.expSWRDataOption = app.expSWRDataCheckbox.Value;
      app.param.thetaOption      = app.thetaFiltCheckBox.Value;
      app.param.thetaLim1        = app.thetaLim1Field.Value;
      app.param.thetaLim2        = app.thetaLim2Field.Value;
      app.param.betaOption       = app.betaFiltCheckBox.Value;
      app.param.betaLim1         = app.betaLim1Field.Value;
      app.param.betaLim2         = app.betaLim2Field.Value;
      app.param.gammaOption      = app.gammaFiltCheckBox.Value;
      app.param.gammaLim1        = app.gammaLim1Field.Value;
      app.param.gammaLim2        = app.gammaLim2Field.Value;
      app.param.hgammaOption     = app.hgammaFiltCheckBox.Value;
      app.param.hgammaLim1       = app.hgammaLim1Field.Value;
      app.param.hgammaLim2       = app.hgammaLim2Field.Value;
      app.param.fROption         = app.fRFiltCheckBox.Value;
      app.param.fRLim1           = app.fRLim1Field.Value;
      app.param.fRLim2           = app.fRLim2Field.Value;
      app.param.spectOption      = app.spectCheckBox.Value;
      app.param.spectLim1        = app.spectLim1Field.Value;
      app.param.spectLim2        = app.spectLim2Field.Value;
      app.param.importStimOption = app.importStimCheckBox.Value;
    end
    
    function updateParamCa(app)
      app.param = [];
      if strmatch(app.fileNumCaButtonGroup.SelectedObject.Text, 'Single Recording')
        app.param.fileNum   = 1;
      elseif strmatch(app.fileNumCaButtonGroup.SelectedObject.Text, 'Multiple (batch process)')
        app.param.fileNum   = 2;
      end
      
      app.param.interpOption           = app.interpCheckbox.Value;
      app.param.samplingInt            = app.samplingIntCaField.Value;
      app.param.cellTypeOption         = app.cellTypeOptionCheckbox.Value;
      app.param.cellType1              = app.cellType1EditField.Value;
      app.param.cellType2              = app.cellType2EditField.Value;
      
      if ~app.baseCorrectCaCheckbox.Value
        app.param.baseCorrectMethod = 0;  
      elseif strmatch(app.baseCorrectCaButtonGroup.SelectedObject.Text, 'Gaussian Filter')
        app.param.baseCorrectMethod = 1;
      elseif strmatch(app.baseCorrectCaButtonGroup.SelectedObject.Text, 'Smoothed Average')
        app.param.baseCorrectMethod = 2;
      end
      
      app.param.CaFiltLim1             = app.CaLim1Field.Value;
      app.param.CaFiltLim2             = app.CaLim2Field.Value;
      app.param.CaFiltOrder            = app.CaFiltOrderField.Value;
      app.param.CaFiltAlpha            = app.CaFiltAlphaField.Value;
      app.param.smoothFactor           = app.smoothFactorField.Value;
      app.param.peakDetectCa           = app.peakDetectCaCheckbox.Value;
      
      if ~app.baseDetectCaCheckbox.Value
        app.param.baseDetectMethod = 0;  
      elseif strmatch(app.baseDetectCaButtonGroup.SelectedObject.Text, 'Lower Quantile')
        app.param.baseDetectMethod = 1;
      elseif strmatch(app.baseDetectCaButtonGroup.SelectedObject.Text, 'Gaussian Fit')
        app.param.baseDetectMethod = 2;
      end
      
      app.param.baseQuant              = app.baseQuantCaField.Value;
      app.param.pkDiffMin              = app.pkDiffMinField.Value;
      app.param.pkSimLim               = app.pkSimLimField.Value;
      app.param.kurtosisMin            = app.kurtosisMinField.Value;
      app.param.kurtosisMax            = app.kurtosisMaxField.Value;
      app.param.excludeQuant           = app.excludeQuantField.Value;
      app.param.plotFitHisto           = app.plotFitHistoCheckBox.Value;

      app.param.sdMult                 = app.sdMultCaField.Value;
      app.param.sdBaseFactor           = app.sdBaseFactorCaField.Value;
      app.param.skipDetectLim          = app.skipDetectLimField.Value;
      app.param.consThreshOption       = app.consistentThreshCheckBox.Value;
      app.param.expCaEvOption          = app.expCaCheckbox.Value;
      app.param.swrCaOption            = app.swrCaCheckbox.Value;
      
      if strmatch(app.swrCaButtonGroup.SelectedObject.Text, 'Use detected SWR duration')
        app.param.useSWRDurationOption = 1;
        app.param.useSWRWindowOption   = 0;
      elseif strmatch(app.swrCaButtonGroup.SelectedObject.Text, 'Use standard swrWindow')
        app.param.useSWRDurationOption = 0;
        app.param.useSWRWindowOption   = 1;
      end

      app.param.swrWindow        = app.swrWindowField.Value;
      app.param.expSWREvOption   = app.expSWRCaCheckbox.Value;
      
      app.param.alignEndOption   = app.alignOptionEndButton.Value;

      app.param.stimCaOption     = app.stimCaCheckBox.Value;
      app.param.stimCaLim1       = app.stimCaLim1Field.Value;
      app.param.stimCaLim2       = app.stimCaLim2Field.Value;
      app.param.expStimEvOption  = app.expStimCaCheckbox.Value;
      
    end

    function updateParamSpk(app)
      app.param = [];
      
      if strmatch(app.fileNumSpkButtonGroup.SelectedObject.Text, 'Single Recording')
        app.param.fileNum  = 1;
      elseif strmatch(app.fileNumSpkButtonGroup.SelectedObject.Text, 'Multiple (batch process)')
        app.param.fileNum  = 2;
      end
      
      app.param.importSpkOption = app.importSpkCheckBox.Value;
      app.param.swrSpkOption    = app.swrSpkButton.Value;
      app.param.swrBstOption    = app.swrBstCheckBox.Value;
      app.param.lfpSpkOption    = app.lfpSpkButton.Value;
      
      if strmatch(app.swrSpkButtonGroup.SelectedObject.Text, 'Use detected SWR duration')
        app.param.useSWRDurationOption = 1;
        app.param.useSWRWindowOption   = 0;
      elseif strmatch(app.swrSpkButtonGroup.SelectedObject.Text, 'Use standard swrWindow')
        app.param.useSWRDurationOption = 0;
        app.param.useSWRWindowOption   = 1;
      end
      app.param.swrWindow       = app.swrWindowField.Value;
      app.param.parseSpkOption  = app.parseSpkCheckBox.Value;
      app.param.expSpkEvOption  = app.expSpkEvCheckBox.Value;
      app.param.expBstEvOption  = app.expBstEvCheckBox.Value;
      app.param.expSWREvOption  = app.expSWRSpkCheckbox.Value;

    end
    
    function updateParamPSC(app)
      app.param = [];
      
      if strmatch(app.fileNumPSCButtonGroup.SelectedObject.Text, 'Single Recording')
        app.param.fileNum = 1;
      elseif strmatch(app.fileNumPSCButtonGroup.SelectedObject.Text, 'Multiple (batch process)')
        app.param.fileNum = 2;
      end
      
      if strmatch(app.pscEventPolarityButtonGroup.SelectedObject.Text, '+')
        app.param.pscEventPolarity = true;
      elseif strmatch(app.pscEventPolarityButtonGroup.SelectedObject.Text, '-')
        app.param.pscEventPolarity = false;
      end
      
      app.param.swrPSQOption    = app.swrPSQCheckBox.Value;
      app.param.importPSCOption = app.importPSCCheckBox.Value;
      app.param.swrPSCOption    = app.swrPSCCheckBox.Value;
      
      if strmatch(app.swrPSCButtonGroup.SelectedObject.Text, 'Use detected SWR duration')
        app.param.useSWRDurationOption = 1;
        app.param.useSWRWindowOption   = 0;
      elseif strmatch(app.swrPSCButtonGroup.SelectedObject.Text, 'Use standard swrWindow')
        app.param.useSWRDurationOption = 0;
        app.param.useSWRWindowOption   = 1;
      end
      app.param.swrWindow      = app.swrWindowField.Value;
      app.param.parsePSCOption = app.parsePSCCheckBox.Value;
      app.param.expPSCEvOption = app.expPSCEvCheckBox.Value;
      app.param.expSWREvOption = app.expSWRPSCCheckbox.Value;
      
      app.param.gammaOption    = app.gammaFiltPSCCheckBox.Value;
      app.param.gammaLim1      = app.gammaLim1PSCField.Value;
      app.param.gammaLim2      = app.gammaLim2PSCField.Value;
      app.param.rOption        = app.rFiltPSCCheckBox.Value;
      app.param.rLim1          = app.rLim1PSCField.Value;
      app.param.rLim2          = app.rLim2PSCField.Value;
      app.param.spectOption    = app.spectPSCCheckBox.Value;
      app.param.spectLim1      = app.spectLim1PSCField.Value;
      app.param.spectLim2      = app.spectLim2PSCField.Value;

    end
    
    function updateSWRReview(app)
      
      % No data file open:
      if isempty(app.selData)
        app.selSWREvLabel.Text = '';
        app.nSWREvLabel.Text   = '';
        app.rejectSWREvButton.Enable = 0;
        app.prevSWREvButton.Enable   = 0;
        app.nextSWREvButton.Enable   = 0;
        
      elseif isfield(app.selData, 'SWR')
        
        % Special logic for zero events:
        if isempty(app.selData.SWR.event)
          app.selEvent = 0;
          app.rejectSWREvButton.Enable = 0;
        else % Enable reject button:
          app.rejectSWREvButton.Enable = 1;
        end
        
        % Enable previous event button (if not first event)
        if app.selEvent > 1
          app.prevSWREvButton.Enable = 1;
        else
          app.prevSWREvButton.Enable = 0;
        end
        
        % Enable next event button (if not last event)
        if app.selEvent < length(app.selData.SWR.event)
          app.nextSWREvButton.Enable = 1;
        else
          app.nextSWREvButton.Enable = 0;
        end
        
        % Special logic when last event has been deleted
        if app.selEvent > length(app.selData.SWR.event)
          app.selEvent = length(app.selData.SWR.event);
        end
        
        % Update labels on UI:
        app.selSWREvLabel.Text = num2str(app.selEvent);
        app.nSWREvLabel.Text   = num2str(length(app.selData.SWR.event));
      
      end
    end
  end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      updateParamLFP(app)
        end

        % Value changed function: lfpFiltCheckBox
        function lfpFiltCheckBoxValueChanged(app, event)
      value = app.lfpFiltCheckBox.Value;
      if (value == 1)
        app.lfpLim1Field.Enable = 1;
        app.lfpLim2Field.Enable = 1;
      else
        app.lfpLim1Field.Enable = 0;
        app.lfpLim2Field.Enable = 0;
      end
        end

        % Value changed function: swFiltCheckBox
        function swFiltCheckBoxValueChanged(app, event)
      value = app.swFiltCheckBox.Value;
      if (value == 1)
        app.swLim1Field.Enable = 1;
        app.swLim2Field.Enable = 1;
      else
        app.swLim1Field.Enable = 0;
        app.swLim2Field.Enable = 0;
      end
        end

        % Value changed function: rFiltCheckBox
        function rFiltCheckBoxValueChanged(app, event)
      value = app.rFiltCheckBox.Value;
      if (value == 1)
        app.rLim1Field.Enable = 1;
        app.rLim2Field.Enable = 1;
      else
        app.rLim1Field.Enable = 0;
        app.rLim2Field.Enable = 0;
      end
        end

        % Value changed function: thetaFiltCheckBox
        function thetaFiltCheckBoxValueChanged(app, event)
      value = app.thetaFiltCheckBox.Value;
      if (value == 1)
        app.thetaLim1Field.Enable = 1;
        app.thetaLim2Field.Enable = 1;
      else
        app.thetaLim1Field.Enable = 0;
        app.thetaLim2Field.Enable = 0;
      end
        end

        % Value changed function: gammaFiltCheckBox
        function gammaFiltCheckBoxValueChanged(app, event)
      value = app.gammaFiltCheckBox.Value;
      if (value == 1)
        app.gammaLim1Field.Enable = 1;
        app.gammaLim2Field.Enable = 1;
      else
        app.gammaLim1Field.Enable = 0;
        app.gammaLim2Field.Enable = 0;
      end
        end

        % Selection changed function: fileTypeButtonGroup
        function fileTypeButtonGroupSelectionChanged(app, event)
      selectedButton = app.fileTypeButtonGroup.SelectedObject;
      if strmatch(selectedButton.Text, 'pClamp')
        app.FsField.Enable = 0;
      elseif strmatch(selectedButton.Text, 'ASCII')
        app.FsField.Enable = 1;
      end
        end

        % Button pushed function: analyzeLFPButton
        function analyzeLFPButtonPushed(app, event)
      updateParamLFP(app);
      if (app.param.fileNum == 1)
        [app.data, app.hand] = analyzeLFPFile([], [], app.param, [], [], [], [], [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
        app.selEvent = 1;
        updateSWRReview(app);
      elseif (app.param.fileNum == 2)
        analyzeLFPBatch(app.param, [], [], [], [], [], []);
      end
        end

        % Selection changed function: resultTree
        function resultTreeSelectionChanged(app, event)
      app.selNode = app.resultTree.SelectedNodes;
      selParent   = app.selNode.Parent;
      
      % Do nothing for 0th level node (filename):
      if ~strcmp(class(selParent), 'matlab.ui.container.Tree')
        % 1st level node - 1st data structure:
        if strcmp(selParent.Text, app.data.saveName)
          app.selVar  = app.data.(app.selNode.Text);
          app.selData = app.data;
          % 1st level node - 2nd data structure (if open):
        elseif isfield(app.data2, 'saveName')
          if strcmp(selParent.Text, app.data2.saveName)
            app.selVar  = app.data2.(app.selNode.Text);
            app.selData = app.data2;
          end
          % 2nd level node - 1st data structure:
        elseif strcmp(selParent.Parent.Text, app.data.saveName)
          app.selVar = app.data.(selParent.Text).(app.selNode.Text);
          % 2nd level node - 2nd data structure:
        elseif isfield(app.data2, 'saveName')
          if strcmp(selParent.Parent.Text, app.data2.saveName)
            app.selVar  = app.data2.(selParent.Text).(app.selNode.Text);
            app.selData = app.data2;
          end
          % 3rd level node - 1st data structure:
        elseif strcmp(selParent.Parent.Parent.Text, app.data.saveName)
          app.selVar = app.data.(selParent.Parent.Text).(selParent.Text).(app.selNode.Text);
          % 3rd level node - 2nd data structure:
        elseif isfield(app.data2, 'saveName')
          if strcmp(selParent.Parent.Parent.Text, app.data2.saveName)
            app.selVar  = app.data2.(selParent.Parent.Text).(selParent.Text).(app.selNode.Text);
            app.selData = app.data2;
          end
          % 4th level node - 1st data structure:
        elseif strcmp(selParent.Parent.Parent.Parent.Text, app.data.saveName)
          app.selVar = app.data.(selParent.Parent.Parent.Text).(selParent.Parent.Text).(selParent.Text).(app.selNode.Text);
          % 4th level node - 2nd data structure:
        elseif isfield(app.data2, 'saveName')
          if strcmp(selParent.Parent.Parent.Parent.Text, app.data2.saveName)
            app.selVar  = app.data2.(selParent.Parent.Parent.Text).(selParent.Parent.Text).(selParent.Text).(app.selNode.Text);
            app.selData = app.data2;
          end
        end
        
        selVarType = class(app.selVar);
        dispVar    = app.selVar;
        selectedVarSize = size(app.selVar);
        varSize         = int2str(selectedVarSize(1)); % String to display
        for i = 2:length(selectedVarSize)
          varSize = [varSize 'x' int2str(selectedVarSize(i))];
        end
        
        % Only display up to 2dim arrays
        if (length(selectedVarSize) > 2)
          dispVar = dispVar(:,:,1);
        end
        
        % Truncate dimensions for display purposes
        if (selectedVarSize(1) > 1000) && (selectedVarSize(2) == 1) % Column Variable
          dispVar = dispVar(1:1000, :);
        elseif (selectedVarSize(1) == 1) && (selectedVarSize(2) > 1000) % Row Variable
          dispVar = dispVar(:, 1:1000);
        elseif (selectedVarSize(1) > 25) && (selectedVarSize(2) > 25) % Array Variable
          dispVar = dispVar(1:25, 1:25);
        end
        
        % Populate display
        app.varTypeTextArea.Value = [varSize ' ' selVarType];
        if strmatch(selVarType,'struct')
          tdata = table(cellstr(app.varTypeTextArea.Value));
        elseif strmatch(selVarType,'char')
          tdata = table(cellstr(dispVar));
        else
          tdata = table(dispVar);
        end
        app.varValueTable.Data = tdata;
        
        % Enable export if structure selected
        if strcmp(app.selNode.Text,'SWR') || strcmp(app.selNode.Text,'spike') || strcmp(app.selNode.Text,'burst') || strcmp(app.selNode.Text,'Ca')
          app.exportEventsButton.Enable = 1;
        else
          app.exportEventsButton.Enable = 0;
        end
        
      end
        end

        % Button pushed function: openFileButton
        function openFileButtonPushed(app, event)
      [fileName, pathName] = uigetfile('.mat','Open analyzed matlab file');
      dataFile = [pathName fileName];
      if ~all(dataFile) return; end
      app.data = load(dataFile);
      [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
      app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
      app.data.saveFile = dataFile; % Update file location in case it has been moved
      app.data.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
      assignin('base', 'data', app.data);
      delete(app.resultTree.Children);
      updateUITree(app, app.data);
      app.selData = app.data;
      updateDataOptions(app);
      app.dataFile1Loaded.Text = app.data.saveName;
      app.openPrevFileButton.Enable = 1;
      app.openNextFileButton.Enable = 1;
      app.openFile2Button.Enable    = 1;
      app.saveFileButton.Enable     = 1;
      app.closeFileButton.Enable    = 1;
      app.analyzeSpkButton.Enable = 1;
      app.selEvent = 1;
      updateSWRReview(app);
        end

        % Button pushed function: openFile2Button
        function openFile2ButtonPushed(app, event)
      [fileName, pathName] = uigetfile('.mat','Open 2nd analyzed matlab file for comparison plots');
      dataFile = [pathName fileName];
      if (dataFile == 0) return; end
      app.data2 = load(dataFile);
      [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
      app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
      app.data2.saveFile = dataFile; % Update file location in case it has been moved
      app.data2.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
      assignin('base', 'data2', app.data2);
      updateUITree(app, app.data2);
      app.selData = app.data2;
      updateDataOptions(app);
      app.dataFile2Loaded.Text = app.data2.saveName;
      app.openFile3Button.Enable = 1;
      app.closeFileButton.Enable = 1;
        end

        % Button pushed function: exportEventsButton
        function exportEventsButtonPushed(app, event)
      if strcmp(app.selNode.Text,'SWR')
        exportSWREvents(app.selData, app.selData.saveFile);
      elseif strcmp(app.selNode.Text,'spike')
        exportSpkEvents(app.selData, app.selData.saveFile);
      elseif strcmp(app.selNode.Text,'burst')
        exportBstEvents(app.selData, app.selData.saveFile);
      elseif strcmp(app.selNode.Text,'Ca')
        exportCaEvents(app.selData, app.selData.saveFile);
      end
        end

        % Button pushed function: plotLFPButton
        function plotLFPButtonPushed(app, event)
      updateParamLFP(app);
      combData = combDataStruct(app.data, app.data2, app.data3);
      if isempty(app.data2) && isempty(app.data3) % only one file loaded
        app.hand.lfpFig = figure('Name',['LFP analysis for ' app.data.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      elseif isempty(app.data3) % two files loaded
        app.hand.lfpFig = figure('Name',['LFP analysis for ' app.data.saveName ' and ' app.data2.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      else % three files loaded
        app.hand.lfpFig = figure('Name',['LFP analysis for ' app.data.saveName ', ' app.data2.saveName ', and ' app.data3.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      end
      app.hand = plotLFPAnalysis(combData, app.hand, app.param, app.param.dsFactor);
      assignin('base', 'hand', app.hand);
        end

        % Button pushed function: closeFileButton
        function closeFileButtonPushed(app, event)
      app.data    = [];
      app.data2   = [];
      app.data3   = [];
      app.selData = [];
      delete(app.resultTree.Children);
      updateDataOptions(app);
      app.dataFile1Loaded.Text = '';
      app.dataFile2Loaded.Text = '';
      app.dataFile3Loaded.Text = '';
      app.openPrevFileButton.Enable = 0;
      app.openNextFileButton.Enable = 0;
      app.openFile2Button.Enable    = 0;
      app.openFile3Button.Enable    = 0;
      app.saveFileButton.Enable     = 0;
      app.saveFileButton.BackgroundColor = [0.96, 0.96, 0.96];
      app.closeFileButton.Enable    = 0;
      updateSWRReview(app);
      evalin('base', 'clear data')
      evalin('base', 'clear data2')
      evalin('base', 'clear data3')
      evalin('base', 'clear hand')
        end

        % Button pushed function: reanalyzeLFPButton
        function reanalyzeLFPButtonPushed(app, event)
      updateParamLFP(app);
      app.param.reAnalyzeOption = 1;
      if (app.param.fileNum == 2) && isempty(app.data)
        analyzeLFPBatch(app.param, [], [], [], [], []);
      elseif ~isempty(app.data)
        [app.data, app.hand] = analyzeLFPFile(app.data, [], app.param, app.data.LFP.dataFile, [], [], [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
        app.selEvent = 1;
        updateSWRReview(app);
      end
        end

        % Value changed function: interpCheckbox
        function interpCheckboxValueChanged(app, event)
      value = app.interpCheckbox.Value;
      if (value == 1)
        app.samplingIntCaField.Enable = 1;
      else
        app.samplingIntCaField.Enable = 0;
      end
        end

        % Value changed function: peakDetectCaCheckbox
        function peakDetectCaCheckboxValueChanged(app, event)
      value  = app.peakDetectCaCheckbox.Value;
      
      if (value == 1)
        app.sdMultCaField.Enable = 1;
        app.baseDetectCaCheckbox.Enable = 1;
        app.baseDetectCaCheckbox.Value  = 1;
        baseDetectCaCheckboxValueChanged(app, event);
        app.consistentThreshCheckBox.Enable = 1;
        app.skipDetectLimField.Enable = 1;
      else
        app.sdMultCaField.Enable = 0;
        app.baseDetectCaCheckbox.Enable = 0;
        app.baseDetectCaCheckbox.Value  = 0;
        baseDetectCaCheckboxValueChanged(app, event);
        app.consistentThreshCheckBox.Enable = 0;
        app.skipDetectLimField.Enable = 0;
      end
        end

        % Button pushed function: analyzeCaButton
        function analyzeCaButtonPushed(app, event)
      updateParamCa(app);
      if (app.param.fileNum == 1)
        
        % Import and process file:
        if ~isempty(app.selData) && (app.param.swrCaOption || app.param.spkCaOption || app.param.stimCaOption)
          [app.data, app.hand] = processCaFile(app.selData, [], app.param, [], [], []);
        else
          [app.data, app.hand] = processCaFile([], [], app.param, [], [], []);
        end
        
        % Trim tSeries if necessary:
        if app.param.stimCaOption
          tSeries = trimCaFile(app.data.Ca.tSeries, app.data.Ca.samplingInt, app.data.LFP.samplingInt, app.data.stim.evStart, app.param);
        else
          tSeries = trimCaFile(app.data.Ca.tSeries, app.data.Ca.samplingInt, [], [], app.param);
        end
        
        % Calculate thresholds
        [app.data.Ca.baseMean, app.data.Ca.baseSD, app.data.Ca.baseThresh, app.data.Ca.peakThresh] = calcCaThresh(tSeries, app.param);
        
        % Analyze file:
        [app.data, app.hand] = analyzeCaFile(app.data, app.hand, app.param, app.data.saveFile, [], []);
        
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      elseif (app.param.fileNum == 2)
        analyzeCaBatch(app.param, [], [], [], [], [], []);
      end

        end

        % Button pushed function: plotCaButton
        function plotCaButtonPushed(app, event)
      updateParamCa(app);
      combData = combDataStruct(app.data, app.data2, app.data3);
      if isempty(app.data2) && isempty(app.data3) % only one file loaded
        app.hand.CaFig = figure('Name',['Calcium analysis for ' app.data.Ca.CaFile],'units','normalized','outerposition',[0 0.05 1 0.95]);
      elseif isempty(app.data3) % two files loaded
        app.hand.CaFig = figure('Name',['Calcium analysis for ' app.data.saveName ' and ' app.data.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      else % three files loaded
        app.hand.CaFig = figure('Name',['Calcium analysis for ' app.data.saveName ', ' app.data2.saveName ', and ' app.data3.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      end
      app.hand = plotCaAnalysis(combData, app.hand, app.param, 1);
      assignin('base', 'hand', app.hand);
        end

        % Button pushed function: reanalyzeCaButton
        function reanalyzeCaButtonPushed(app, event)
      updateParamCa(app);
      app.param.reAnalyzeOption = 1;
      if (app.param.fileNum == 2) && isempty(app.data)
        analyzeCaBatch(app.param, [], [], [], [], [], []);
      elseif ~isempty(app.data)
        
        % Pre-process if options require:
        if (app.param.baseCorrectMethod > 0) || app.param.interpOption
          [app.data, app.hand] = processCaFile(app.selData, [], app.param, [], [], []);
        end
        
        % Calculate threshold if required:
        if app.param.baseDetectMethod > 0
          [app.data.Ca.baseMean, app.data.Ca.baseSD, app.data.Ca.baseThresh, app.data.Ca.peakThresh] = calcCaThresh(app.data.Ca.tSeries, app.data.Ca.timing, app.param);
        end
        
        [app.data, app.hand] = analyzeCaFile(app.data, app.hand, app.param, app.data.saveFile, [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      end
      
        end

        % Value changed function: rmsSWRCheckbox
        function rmsSWRCheckboxValueChanged(app, event)
      value = app.rmsSWRCheckbox.Value;
      if (value == 1)
        app.rmsPeriodField.Enable = 1;
      else
        app.rmsPeriodField.Enable = 0;
      end
        end

        % Value changed function: peakDetectSWRCheckbox
        function peakDetectSWRCheckboxValueChanged(app, event)
      value = app.peakDetectSWRCheckbox.Value;
      if (value == 1)
        app.sdMultLFPField.Enable = 1;
        app.baseQuantLFPField.Enable = 1;
        app.rmsMinEvDiffField.Enable = 1;
      else
        app.sdMultLFPField.Enable = 0;
        app.baseQuantLFPField.Enable = 0;
        app.rmsMinEvDiffField.Enable = 0;
      end
        end

        % Value changed function: swrDetectCheckBox
        function swrDetectCheckBoxValueChanged(app, event)
      value = app.swrDetectCheckBox.Value;
      if (value == 1)
        % Enable fields:
        app.swFiltCheckBox.Enable = 1;
        app.rFiltCheckBox.Enable  = 1;
        app.rmsSWRCheckbox.Enable = 1;
        app.peakDetectSWRCheckbox.Enable = 1;
        app.swLim1Field.Enable = 1;
        app.swLim2Field.Enable = 1;
        app.rLim1Field.Enable = 1;
        app.rLim2Field.Enable = 1;
        app.rmsMinEvDiffField.Enable = 1;
        app.rmsPeriodField.Enable = 1;
        app.sdMultLFPField.Enable = 1;
        app.baseQuantLFPField.Enable = 1;
        app.SWAndRippleButton.Enable = 1;
        app.SWOnlyButton.Enable = 1;
        app.RippleOnlyButton.Enable = 1;
        
        % Update values:
        app.swFiltCheckBox.Value = 1;
        app.rFiltCheckBox.Value  = 1;
        app.rmsSWRCheckbox.Value = 1;
        app.peakDetectSWRCheckbox.Value = 1;
        app.expSWREvCheckbox.Value = 1;
        app.expSWRDataCheckbox.Value = 1;
      else
        % Update values:
        app.swFiltCheckBox.Value = 0;
        app.rFiltCheckBox.Value  = 0;
        app.rmsSWRCheckbox.Value = 0;
        app.peakDetectSWRCheckbox.Value = 0;
        app.expSWREvCheckbox.Value = 0;
        app.expSWRDataCheckbox.Value = 0;
        
        % Enable fields:
        app.swFiltCheckBox.Enable = 0;
        app.rFiltCheckBox.Enable  = 0;
        app.rmsSWRCheckbox.Enable = 0;
        app.peakDetectSWRCheckbox.Enable = 0;
        app.swLim1Field.Enable = 0;
        app.swLim2Field.Enable = 0;
        app.rLim1Field.Enable = 0;
        app.rLim2Field.Enable = 0;
        app.rmsMinEvDiffField.Enable = 0;
        app.rmsPeriodField.Enable = 0;
        app.sdMultLFPField.Enable = 0;
        app.baseQuantLFPField.Enable = 0;
        app.SWAndRippleButton.Enable = 0;
        app.SWOnlyButton.Enable = 0;
        app.RippleOnlyButton.Enable = 0;
        
      end
        end

        % Value changed function: spectCheckBox
        function spectCheckBoxValueChanged(app, event)
      value = app.spectCheckBox.Value;
      if (value == 1)
        app.spectLim1Field.Enable = 1;
        app.spectLim2Field.Enable = 1;
      else
        app.spectLim1Field.Enable = 0;
        app.spectLim2Field.Enable = 0;
      end
        end

        % Value changed function: betaFiltCheckBox
        function betaFiltCheckBoxValueChanged(app, event)
      value = app.betaFiltCheckBox.Value;
      if (value == 1)
        app.betaLim1Field.Enable = 1;
        app.betaLim2Field.Enable = 1;
      else
        app.betaLim1Field.Enable = 0;
        app.betaLim2Field.Enable = 0;
      end
        end

        % Value changed function: hgammaFiltCheckBox
        function hgammaFiltCheckBoxValueChanged(app, event)
      value = app.hgammaFiltCheckBox.Value;
      if (value == 1)
        app.hgammaLim1Field.Enable = 1;
        app.hgammaLim2Field.Enable = 1;
      else
        app.hgammaLim1Field.Enable = 0;
        app.hgammaLim2Field.Enable = 0;
      end
        end

        % Button pushed function: analyzeSpkButton
        function analyzeSpkButtonPushed(app, event)
      updateParamSpk(app);
      if (app.param.fileNum == 1)
        if isempty(app.selData)
          [app.data, app.hand] = analyzeSpkFile([], [], app.param, [], [], [], [], []);
        else
          [app.data, app.hand] = analyzeSpkFile(app.selData, [], app.param, [], [], [], [], []);
        end
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      elseif (app.param.fileNum == 2)
        analyzeSpkBatch(app.param, [], [], [], [], [], []);
      end
        end

        % Value changed function: cellCheckBox
        function cellCheckBoxValueChanged(app, event)
      value = app.cellCheckBox.Value;
      if (value == 1)
        app.cellChannelField.Enable = 1;
        app.pscPosPolarityButton.Enable = 1;
        app.pscNegPolarityButton.Enable = 1;
      else
        app.cellChannelField.Enable = 0;
        app.pscPosPolarityButton.Enable = 0;
        app.pscNegPolarityButton.Enable = 0;
      end
        end

        % Button pushed function: plotSpkButton
        function plotSpkButtonPushed(app, event)
      updateParamSpk(app);
      combData = combDataStruct(app.data, app.data2, app.data3);
      if isempty(app.data2) && isempty(app.data3) % only one file loaded
        app.hand.spkFig = figure('Name',['Spike analysis for ' app.data.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      elseif isempty(app.data3) % two files loaded
        app.hand.spkFig = figure('Name',['Spike analysis for ' app.data.saveName ' and ' app.data2.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      else % three files loaded
        app.hand.spkFig = figure('Name',['Spike analysis for ' app.data.saveName ', ' app.data2.saveName ', and ' app.data3.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      end
      app.hand = plotSpkAnalysis(combData, app.hand, app.param, 1);
      assignin('base', 'hand', app.hand);
        end

        % Value changed function: swrBstCheckBox
        function swrBstCheckBoxValueChanged(app, event)
      value = app.swrBstCheckBox.Value;
      if (value == 1)
        app.expBstEvCheckBox.Enable = 1;
        app.expBstEvCheckBox.Value  = 1;
      else
        app.expBstEvCheckBox.Enable = 0;
        app.expBstEvCheckBox.Value  = 0;
      end
        end

        % Callback function
        function swrSpkCheckBoxValueChanged(app, event)
      value = app.swrSpkCheckBox.Value;
      if (value == 1)
        app.swrBstCheckBox.Enable = 1;
        app.swrBstCheckBox.Value = 1;
        app.useSWRDurationSpkButton.Enable = 1;
        app.useSWRWindowSpkButton.Enable = 1;
      else
        app.swrBstCheckBox.Enable = 0;
        app.swrBstCheckBox.Value = 0;
        app.useSWRDurationSpkButton.Enable = 0;
        app.useSWRWindowSpkButton.Enable = 0;
      end
      swrBstCheckBoxValueChanged(app, event);
        end

        % Button pushed function: openNextFileButton
        function openNextFileButtonPushed(app, event)
      currFileIndex = find(contains(app.dataFiles, app.data.saveName));
      if (currFileIndex + 1) <= length(app.dataFiles)
        dataFile = [app.parentPath app.dataFiles{currFileIndex + 1}];
        app.data = load(dataFile);
        [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
        app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
        app.data.saveFile = dataFile; % Update file location in case it has been moved
        app.data.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.openPrevFileButton.Enable = 1;
        app.openNextFileButton.Enable = 1;
        app.openFile2Button.Enable    = 1;
        app.saveFileButton.Enable     = 1;
        app.closeFileButton.Enable    = 1;
        app.selEvent = 1;
        updateSWRReview(app);
      end
        end

        % Button pushed function: openPrevFileButton
        function openPrevFileButtonPushed(app, event)
      currFileIndex = find(contains(app.dataFiles, app.data.saveName));
      if (currFileIndex - 1) >= 1
        dataFile = [app.parentPath app.dataFiles{currFileIndex - 1}];
        app.data = load(dataFile);
        [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
        app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
        app.data.saveFile = dataFile; % Update file location in case it has been moved
        app.data.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.openPrevFileButton.Enable = 1;
        app.openNextFileButton.Enable = 1;
        app.openFile2Button.Enable    = 1;
        app.saveFileButton.Enable     = 1;
        app.closeFileButton.Enable    = 1;
        app.selEvent = 1;
        updateSWRReview(app);
      end
        end

        % Button pushed function: prevSWREvButton
        function prevSWREvButtonPushed(app, event)
      app.selEvent = app.selEvent - 1;
      updateSWRReview(app);
      app.hand = plotLFPUpdate(app.selData, app.hand, app.param, app.selEvent);
        end

        % Button pushed function: nextSWREvButton
        function nextSWREvButtonPushed(app, event)
      app.selEvent = app.selEvent + 1;
      updateSWRReview(app);
      app.hand = plotLFPUpdate(app.selData, app.hand, app.param, app.selEvent);
        end

        % Button pushed function: rejectSWREvButton
        function rejectSWREvButtonPushed(app, event)
      app.selData = rejectSWREvent(app.selData, app.selEvent);
      updateSWRReview(app);
      assignin('base', 'data', app.selData);
      delete(app.resultTree.Children);
      updateUITree(app, app.selData);
      app.hand = plotLFPUpdate(app.selData, app.hand, app.param, app.selEvent);
      
      % Add indicator to save button that file has been edited:
      app.saveFileButton.BackgroundColor = [0.64, 0.08, 0.10];
        end

        % Button pushed function: saveFileButton
        function saveFileButtonPushed(app, event)
      updateParamLFP(app);
      
      % Export SWR event table (if selected)
      if app.param.expSWREvOption && app.param.swrOption
        if ~isfield (app.selData.SWR, 'expEvFile')
          [parPath, dataFileName, ~] = parsePath(app.selData.saveFile);
          app.selData.SWR.expEvFile = [parPath dataFileName '_swrEvents.csv'];
        end
        exportSWREvents(app.selData, app.selData.saveFile, app.selData.SWR.expEvFile);
      end
      
      % Export SWR data file (if selected)
      if app.param.expSWRDataOption && app.param.swrOption
        if ~isfield (app.selData.SWR, 'expDataFile')
          [parPath, dataFileName, ~] = parsePath(app.selData.saveFile);
          app.selData.SWR.expDataFile = [parPath dataFileName '_swrData.txt'];
        end
        exportSWRData(app.selData, app.param, app.selData.saveFile, app.selData.SWR.expDataFile);
      end
      
      % Save .mat file
      saveStruct = app.selData;
      save(app.selData.saveFile,'-struct','saveStruct');
      
      % Return to default color to indiciate no pending unsaved data.
      app.saveFileButton.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Value changed function: swrCaCheckbox
        function swrCaCheckboxValueChanged(app, event)
      value = app.swrCaCheckbox.Value;
      if (value == 1)
        app.useSWRDurationCaButton.Enable = 1;
        app.useSWRWindowCaButton.Enable   = 1;
        app.expSWRCaCheckbox.Value        = 1;
      else
        app.useSWRDurationCaButton.Enable = 0;
        app.useSWRWindowCaButton.Enable   = 0;
        app.expSWRCaCheckbox.Value        = 0;
      end
      
      value2 = app.stimCaCheckBox.Value;
      if value || value2
        app.alignOptionStartButton.Enable = 1;
        app.alignOptionEndButton.Enable   = 1;
      else
        app.alignOptionStartButton.Enable = 0;
        app.alignOptionEndButton.Enable   = 0;
      end
        end

        % Selection changed function: fileNumCaButtonGroup
        function fileNumCaButtonGroupSelectionChanged(app, event)
      selectedButton = app.fileNumCaButtonGroup.SelectedObject;
      
      if strmatch(selectedButton.Text, 'Single Recording')
        app.consistentThreshCheckBox.Enable = 0;
      elseif strmatch(selectedButton.Text, 'Multiple (batch process)')
        app.consistentThreshCheckBox.Enable = 1;
      end
        end

        % Button pushed function: openFile3Button
        function openFile3ButtonPushed(app, event)
      [fileName, pathName] = uigetfile('.mat','Open 3rd analyzed matlab file for comparison plots');
      dataFile = [pathName fileName];
      if (dataFile == 0) return; end
      app.data3 = load(dataFile);
      [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
      app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
      app.data3.saveFile = dataFile; % Update file location in case it has been moved
      app.data3.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
      assignin('base', 'data3', app.data3);
      updateUITree(app, app.data3);
      app.selData = app.data3;
      updateDataOptions(app);
      app.dataFile3Loaded.Text = app.data3.saveName;
      app.closeFileButton.Enable = 1;
        end

        % Value changed function: stimCaCheckBox
        function stimCaCheckBoxValueChanged(app, event)
      value = app.stimCaCheckBox.Value;
      
      if value == 1
        app.stimCaLim1Field.Enable   = 1;
        app.stimCaLim2Field.Enable   = 1;
        app.expStimCaCheckbox.Enable = 1;
        app.expStimCaCheckbox.Value  = 1;
      else
        app.stimCaLim1Field.Enable   = 0;
        app.stimCaLim2Field.Enable   = 0;
        app.expStimCaCheckbox.Enable = 0;
        app.expStimCaCheckbox.Value  = 0;
      end
      
      value2 = app.swrCaCheckbox.Value;
      if value || value2
        app.alignOptionStartButton.Enable = 1;
        app.alignOptionEndButton.Enable   = 1;
      else
        app.alignOptionStartButton.Enable = 0;
        app.alignOptionEndButton.Enable   = 0;
      end
      
        end

        % Selection changed function: baseDetectCaButtonGroup
        function baseDetectCaButtonGroupSelectionChanged(app, event)
      selectedButton = app.baseDetectCaButtonGroup.SelectedObject;
      if app.baseDetectCaCheckbox.Value
        if strmatch(selectedButton.Text, 'Lower Quantile')
          app.baseQuantCaField.Enable     = 1;
          app.pkDiffMinField.Enable       = 0;
          app.pkSimLimField.Enable        = 0;
          app.kurtosisMinField.Enable     = 0;
          app.kurtosisMaxField.Enable     = 0;
          app.excludeQuantField.Enable    = 0;
          app.plotFitHistoCheckBox.Enable = 0;
        elseif strmatch(selectedButton.Text, 'Gaussian Fit')
          app.baseQuantCaField.Enable     = 0;
          app.pkDiffMinField.Enable       = 1;
          app.pkSimLimField.Enable        = 1;
          app.kurtosisMinField.Enable     = 1;
          app.kurtosisMaxField.Enable     = 1;
          app.excludeQuantField.Enable    = 1;
          app.plotFitHistoCheckBox.Enable = 1;
        end
      end
        end

        % Selection changed function: baseCorrectCaButtonGroup
        function baseCorrectCaButtonGroupSelectionChanged(app, event)
      selectedButton = app.baseCorrectCaButtonGroup.SelectedObject;
      if app.baseCorrectCaCheckbox.Value
        if strmatch(selectedButton.Text, 'Gaussian Filter')
          app.CaLim1Field.Enable       = 1;
          app.CaLim2Field.Enable       = 1;
          app.CaFiltOrderField.Enable  = 1;
          app.CaFiltAlphaField.Enable  = 1;
          app.smoothFactorField.Enable = 0;
        elseif strmatch(selectedButton.Text, 'Smoothed Average')
          app.CaLim1Field.Enable       = 0;
          app.CaLim2Field.Enable       = 0;
          app.CaFiltOrderField.Enable  = 0;
          app.CaFiltAlphaField.Enable  = 0;
          app.smoothFactorField.Enable = 1;
        end
      end
        end

        % Value changed function: baseDetectCaCheckbox
        function baseDetectCaCheckboxValueChanged(app, event)
      value = app.baseDetectCaCheckbox.Value;
      if value
        baseDetectCaButtonGroupSelectionChanged(app, event);
      else
        app.baseQuantCaField.Enable     = 0;
        app.pkDiffMinField.Enable       = 0;
        app.pkSimLimField.Enable        = 0;
        app.kurtosisMinField.Enable     = 0;
        app.kurtosisMaxField.Enable     = 0;
        app.excludeQuantField.Enable    = 0;
        app.plotFitHistoCheckBox.Enable = 0;
      end

        end

        % Value changed function: baseCorrectCaCheckbox
        function baseCorrectCaCheckboxValueChanged(app, event)
      value = app.baseCorrectCaCheckbox.Value;
      if value
        baseCorrectCaButtonGroupSelectionChanged(app, event);
      else
        app.CaLim1Field.Enable       = 0;
        app.CaLim2Field.Enable       = 0;
        app.CaFiltOrderField.Enable  = 0;
        app.CaFiltAlphaField.Enable  = 0;
        app.smoothFactorField.Enable = 0;
      end
        end

        % Button pushed function: analyzePSCButton
        function analyzePSCButtonPushed(app, event)
      updateParamPSC(app);
      if (app.param.fileNum == 1)
        if isempty(app.selData)
          [app.data, app.hand] = analyzePSCFile([], [], app.param, [], [], []);
        else
          [app.data, app.hand] = analyzePSCFile(app.selData, [], app.param, [], [], []);
        end
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.closeFileButton.Enable = 1;
        app.openFile2Button.Enable = 1;
      elseif (app.param.fileNum == 2)
        analyzePSCBatch(app.param, [], [], [], []);
      end
        end

        % Button pushed function: plotPSCButton
        function plotPSCButtonPushed(app, event)
      updateParamPSC(app);
      combData = combDataStruct(app.data, app.data2, app.data3);
      if isempty(app.data2) && isempty(app.data3) % only one file loaded
        app.hand.pscFig = figure('Name',['PSC analysis for ' app.data.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      elseif isempty(app.data3) % two files loaded
        app.hand.pscFig = figure('Name',['PSC analysis for ' app.data.saveName ' and ' app.data2.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      else % three files loaded
        app.hand.pscFig = figure('Name',['PSC analysis for ' app.data.saveName ', ' app.data2.saveName ', and ' app.data3.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      end
      app.hand = plotPSCAnalysis(combData, app.hand, app.param, 1);
      assignin('base', 'hand', app.hand);
        end

        % Value changed function: swrPSCCheckBox
        function swrPSCCheckBoxValueChanged(app, event)
      value = app.swrPSCCheckBox.Value;
      if (value == 1)
        app.useSWRDurationPSCButton.Enable = 1;
        app.useSWRWindowPSCButton.Enable = 1;
      else
        app.useSWRDurationPSCButton.Enable = 0;
        app.useSWRWindowPSCButton.Enable = 0;
      end
        end

        % Button pushed function: reanalyzeSpkButton
        function reanalyzeSpkButtonPushed(app, event)
      updateParamSpk(app);
      app.param.reAnalyzeOption = 1;
      if (app.param.fileNum == 2) && isempty(app.data)
        analyzeSpkBatch(app.param, [], [], [], [], [], []);
      elseif ~isempty(app.data)
        [app.data, app.hand] = analyzeSpkFile(app.data, [], app.param, app.data.saveFile, [], [], [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      end
        end

        % Button pushed function: reanalyzePSCButton
        function reanalyzePSCButtonPushed(app, event)
      updateParamPSC(app);
      app.param.reAnalyzeOption = 1;
      if (app.param.fileNum == 2) && isempty(app.data)
        analyzePSCBatch(app.param, [], [], [], []);
      elseif ~isempty(app.data)
        [app.data, app.hand] = analyzePSCFile(app.data, [], app.param, app.data.saveFile, [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      end
        end

        % Value changed function: fRFiltCheckBox
        function fRFiltCheckBoxValueChanged(app, event)
      value = app.fRFiltCheckBox.Value;
      if (value == 1)
        app.fRLim1Field.Enable = 1;
        app.fRLim2Field.Enable = 1;
      else
        app.fRLim1Field.Enable = 0;
        app.fRLim2Field.Enable = 0;
      end
        end

        % Value changed function: gammaFiltPSCCheckBox
        function gammaFiltPSCCheckBoxValueChanged(app, event)
      value = app.gammaFiltPSCCheckBox.Value;
      if (value == 1)
        app.gammaLim1PSCField.Enable = 1;
        app.gammaLim2PSCField.Enable = 1;
      else
        app.gammaLim1PSCField.Enable = 0;
        app.gammaLim2PSCField.Enable = 0;
      end
        end

        % Value changed function: rFiltPSCCheckBox
        function rFiltPSCCheckBoxValueChanged(app, event)
      value = app.rFiltPSCCheckBox.Value;
      if (value == 1)
        app.rLim1PSCField.Enable = 1;
        app.rLim2PSCField.Enable = 1;
      else
        app.rLim1PSCField.Enable = 0;
        app.rLim2PSCField.Enable = 0;
      end
        end

        % Value changed function: spectPSCCheckBox
        function spectPSCCheckBoxValueChanged(app, event)
      value = app.spectPSCCheckBox.Value;
      if (value == 1)
        app.spectLim1PSCField.Enable = 1;
        app.spectLim2PSCField.Enable = 1;
      else
        app.spectLim1PSCField.Enable = 0;
        app.spectLim2PSCField.Enable = 0;
      end
        end

        % Value changed function: notchFiltCheckBox
        function notchFiltCheckBoxValueChanged(app, event)
      value = app.notchFiltCheckBox.Value;
      if (value == 1)
        app.notchFiltFreqField.Enable = 1;
      else
        app.notchFiltFreqField.Enable = 0;
      end
        end

        % Value changed function: cellTypeOptionCheckbox
        function cellTypeOptionCheckboxValueChanged(app, event)
      value = app.cellTypeOptionCheckbox.Value;
      if value
        app.cellType1EditField.Enable = 1;
        app.cellType2EditField.Enable = 1;
      else
        app.cellType1EditField.Enable = 0;
        app.cellType2EditField.Enable = 0;
      end
        end

        % Selection changed function: CAAnalysisTypeButtonGroup
        function CAAnalysisTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CAAnalysisTypeButtonGroup.SelectedObject;
            if strmatch(selectedButton.Text, 'Spike - SWR (previously detected events)')
                app.swrBstCheckBox.Enable = 1;
                app.swrBstCheckBox.Value  = 1;
            elseif strmatch(selectedButton.Text, 'Spike - LFP (entire duration of file)')
                app.swrBstCheckBox.Enable = 0;
                app.swrBstCheckBox.Value  = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.swrAnalysisUIFigure = uifigure('Visible', 'off');
            app.swrAnalysisUIFigure.Name = 'SWR Analysis';
            app.TabGroup = uitabgroup(app.swrAnalysisUIFigure);
            app.LFPTab = uitab(app.TabGroup);
            app.LFPTab.Title = 'Local Field Potential';
            app.fileNumLFPButtonGroup = uibuttongroup(app.LFPTab);
            app.fileNumLFPButtonGroup.Title = 'Number of Recordings';
            app.SingleLFPButton = uiradiobutton(app.fileNumLFPButtonGroup);
            app.SingleLFPButton.Text = 'Single Recording';
            app.MultipleLFPButton = uiradiobutton(app.fileNumLFPButtonGroup);
            app.MultipleLFPButton.Text = 'Multiple (batch process)';
            app.MultipleLFPButton.Value = true;
            app.fileTypeButtonGroup = uibuttongroup(app.LFPTab);
            app.fileTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @fileTypeButtonGroupSelectionChanged, true);
            app.fileTypeButtonGroup.Title = 'File Type';
            app.pClampButton = uiradiobutton(app.fileTypeButtonGroup);
            app.pClampButton.Text = 'pClamp';
            app.pClampButton.Value = true;
            app.ASCIIButton = uiradiobutton(app.fileTypeButtonGroup);
            app.ASCIIButton.Text = 'ASCII';
            app.lfpFiltCheckBox = uicheckbox(app.LFPTab);
            app.lfpFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @lfpFiltCheckBoxValueChanged, true);
            app.lfpFiltCheckBox.Text = 'LFP Filter';
            app.lfpFiltCheckBox.Value = true;
            app.lfpLim1Label = uilabel(app.LFPTab);
            app.lfpLim1Label.Text = 'Lower:';
            app.lfpLim1Field = uieditfield(app.LFPTab, 'numeric');
            app.lfpLim1Field.Value = 1;
            app.lfpLim2Label = uilabel(app.LFPTab);
            app.lfpLim2Label.Text = 'Upper:';
            app.lfpLim2Field = uieditfield(app.LFPTab, 'numeric');
            app.lfpLim2Field.Value = 1000;
            app.SamplingFrequencyLabel = uilabel(app.LFPTab);
            app.SamplingFrequencyLabel.Text = 'Sampling Frequency:';
            app.FsField = uieditfield(app.LFPTab, 'numeric');
            app.FsField.Enable = 'off';
            app.FsField.Value = 3000;
            app.LFPChannelLabel = uilabel(app.LFPTab);
            app.LFPChannelLabel.Text = 'LFP Channel:';
            app.lpfChannelField = uieditfield(app.LFPTab, 'numeric');
            app.lpfChannelField.Value = 2;
            app.DownsampleFactorLabel = uilabel(app.LFPTab);
            app.DownsampleFactorLabel.Text = 'Downsample Factor:';
            app.dsFactorField = uieditfield(app.LFPTab, 'numeric');
            app.dsFactorField.Value = 1;
            app.spectCheckBox = uicheckbox(app.LFPTab);
            app.spectCheckBox.ValueChangedFcn = createCallbackFcn(app, @spectCheckBoxValueChanged, true);
            app.spectCheckBox.Text = 'Spectral Analysis';
            app.spectCheckBox.Value = true;
            app.lfpFiltLamp = uilamp(app.LFPTab);
            app.spectLamp = uilamp(app.LFPTab);
            app.analyzeLFPButton = uibutton(app.LFPTab, 'push');
            app.analyzeLFPButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeLFPButtonPushed, true);
            app.analyzeLFPButton.Text = 'Analyze LFP';
            app.plotLFPButton = uibutton(app.LFPTab, 'push');
            app.plotLFPButton.ButtonPushedFcn = createCallbackFcn(app, @plotLFPButtonPushed, true);
            app.plotLFPButton.Enable = 'off';
            app.plotLFPButton.Text = 'Plot LFP Analysis';
            app.reanalyzeLFPButton = uibutton(app.LFPTab, 'push');
            app.reanalyzeLFPButton.ButtonPushedFcn = createCallbackFcn(app, @reanalyzeLFPButtonPushed, true);
            app.reanalyzeLFPButton.Text = 'Re-Analyze LFP';
            app.HzLabel = uilabel(app.LFPTab);
            app.HzLabel.Text = 'Hz';
            app.SWRDetectionPanel = uipanel(app.LFPTab);
            app.SWRDetectionPanel.Title = 'SWR Detection';
            app.swFiltCheckBox = uicheckbox(app.SWRDetectionPanel);
            app.swFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @swFiltCheckBoxValueChanged, true);
            app.swFiltCheckBox.Text = 'SW Filter';
            app.rFiltCheckBox = uicheckbox(app.SWRDetectionPanel);
            app.rFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @rFiltCheckBoxValueChanged, true);
            app.rFiltCheckBox.Text = 'Ripple Filter';
            app.swFiltLamp = uilamp(app.SWRDetectionPanel);
            app.rFiltLamp = uilamp(app.SWRDetectionPanel);
            app.expSWREvCheckbox = uicheckbox(app.SWRDetectionPanel);
            app.expSWREvCheckbox.Text = 'Export SWR event files';
            app.msLabel_2 = uilabel(app.SWRDetectionPanel);
            app.msLabel_2.Text = 'ms';
            app.msLabel_3 = uilabel(app.SWRDetectionPanel);
            app.msLabel_3.Text = 'ms';
            app.peakDetectSWRCheckbox = uicheckbox(app.SWRDetectionPanel);
            app.peakDetectSWRCheckbox.ValueChangedFcn = createCallbackFcn(app, @peakDetectSWRCheckboxValueChanged, true);
            app.peakDetectSWRCheckbox.Text = 'Peak Detection';
            app.rmsSWRCheckbox = uicheckbox(app.SWRDetectionPanel);
            app.rmsSWRCheckbox.ValueChangedFcn = createCallbackFcn(app, @rmsSWRCheckboxValueChanged, true);
            app.rmsSWRCheckbox.Text = 'RMS Calculation';
            app.HzLabel_6 = uilabel(app.SWRDetectionPanel);
            app.HzLabel_6.Text = 'Hz';
            app.HzLabel_7 = uilabel(app.SWRDetectionPanel);
            app.HzLabel_7.Text = 'Hz';
            app.rmsSWRLamp = uilamp(app.SWRDetectionPanel);
            app.peakDetectSWRLamp = uilamp(app.SWRDetectionPanel);
            app.expSWRDataCheckbox = uicheckbox(app.SWRDetectionPanel);
            app.expSWRDataCheckbox.Text = 'Export SWR data files';
            app.swLim1Label = uilabel(app.SWRDetectionPanel);
            app.swLim1Label.Text = 'Lower:';
            app.swLim1Field = uieditfield(app.SWRDetectionPanel, 'numeric');
            app.swLim1Field.Value = 1;
            app.swLim2Label = uilabel(app.SWRDetectionPanel);
            app.swLim2Label.Text = 'Upper:';
            app.swLim2Field = uieditfield(app.SWRDetectionPanel, 'numeric');
            app.swLim2Field.Value = 30;
            app.rLim1Label = uilabel(app.SWRDetectionPanel);
            app.rLim1Label.Text = 'Lower:';
            app.rLim1Field = uieditfield(app.SWRDetectionPanel, 'numeric');
            app.rLim1Field.Value = 120;
            app.rLim2Label = uilabel(app.SWRDetectionPanel);
            app.rLim2Label.Text = 'Upper:';
            app.rLim2Field = uieditfield(app.SWRDetectionPanel, 'numeric');
            app.rLim2Field.Value = 220;
            app.BLQuantileLabel = uilabel(app.SWRDetectionPanel);
            app.BLQuantileLabel.Text = 'BL Quantile:';
            app.baseQuantLFPField = uieditfield(app.SWRDetectionPanel, 'numeric');
            app.baseQuantLFPField.Value = 0.95;
            app.RMSPeriodLabel = uilabel(app.SWRDetectionPanel);
            app.RMSPeriodLabel.Text = ' RMS Period:';
            app.rmsPeriodField = uieditfield(app.SWRDetectionPanel, 'numeric');
            app.rmsPeriodField.Value = 50;
            app.SWRStandardWindowLabel = uilabel(app.SWRDetectionPanel);
            app.SWRStandardWindowLabel.Text = 'SWR Standard Window:';
            app.swrWindowField = uieditfield(app.SWRDetectionPanel, 'numeric');
            app.swrWindowField.Value = 100;
            app.MinEventDifferenceLabel = uilabel(app.SWRDetectionPanel);
            app.MinEventDifferenceLabel.Text = 'Min Event Difference: ';
            app.rmsMinEvDiffField = uieditfield(app.SWRDetectionPanel, 'numeric');
            app.rmsMinEvDiffField.Value = 50;
            app.SDMultipleThresholdLabel_2 = uilabel(app.SWRDetectionPanel);
            app.SDMultipleThresholdLabel_2.Text = 'SD Multiple Threshold:';
            app.sdMultLFPField = uieditfield(app.SWRDetectionPanel, 'numeric');
            app.sdMultLFPField.Value = 6;
            app.expLFPAveCheckbox = uicheckbox(app.SWRDetectionPanel);
            app.expLFPAveCheckbox.Text = 'Export average files';
            app.OtherFiltersPanel = uipanel(app.LFPTab);
            app.OtherFiltersPanel.Title = 'Other Filters';
            app.thetaFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
            app.thetaFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @thetaFiltCheckBoxValueChanged, true);
            app.thetaFiltCheckBox.Text = 'Theta Filter';
            app.thetaFiltCheckBox.Value = true;
            app.gammaFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
            app.gammaFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @gammaFiltCheckBoxValueChanged, true);
            app.gammaFiltCheckBox.Text = 'Gamma Filter';
            app.gammaFiltCheckBox.Value = true;
            app.thetaFiltLamp = uilamp(app.OtherFiltersPanel);
            app.gammaFiltLamp = uilamp(app.OtherFiltersPanel);
            app.thetaLim1Label = uilabel(app.OtherFiltersPanel);
            app.thetaLim1Label.Text = 'Lower:';
            app.thetaLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.thetaLim1Field.Value = 4;
            app.thetaLim2Label = uilabel(app.OtherFiltersPanel);
            app.thetaLim2Label.Text = 'Upper:';
            app.thetaLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.thetaLim2Field.Value = 12;
            app.gammaLim1Label = uilabel(app.OtherFiltersPanel);
            app.gammaLim1Label.Text = 'Lower:';
            app.gammaLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.gammaLim1Field.Value = 25;
            app.gammaLim2Label = uilabel(app.OtherFiltersPanel);
            app.gammaLim2Label.Text = 'Upper:';
            app.gammaLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.gammaLim2Field.Value = 55;
            app.hgammaFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
            app.hgammaFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @hgammaFiltCheckBoxValueChanged, true);
            app.hgammaFiltCheckBox.Text = 'High Gamma Filter';
            app.hgammaFiltCheckBox.Value = true;
            app.hgammaFiltLamp = uilamp(app.OtherFiltersPanel);
            app.gammaLim1Label_2 = uilabel(app.OtherFiltersPanel);
            app.gammaLim1Label_2.Text = 'Lower:';
            app.hgammaLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.hgammaLim1Field.Value = 65;
            app.gammaLim2Label_2 = uilabel(app.OtherFiltersPanel);
            app.gammaLim2Label_2.Text = 'Upper:';
            app.hgammaLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.hgammaLim2Field.Value = 85;
            app.betaFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
            app.betaFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @betaFiltCheckBoxValueChanged, true);
            app.betaFiltCheckBox.Text = 'Beta Filter';
            app.betaFiltLamp = uilamp(app.OtherFiltersPanel);
            app.thetaLim1Label_2 = uilabel(app.OtherFiltersPanel);
            app.thetaLim1Label_2.Text = 'Lower:';
            app.betaLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.betaLim1Field.Enable = 'off';
            app.betaLim1Field.Value = 13;
            app.thetaLim2Label_2 = uilabel(app.OtherFiltersPanel);
            app.thetaLim2Label_2.Text = 'Upper:';
            app.betaLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.betaLim2Field.Enable = 'off';
            app.betaLim2Field.Value = 24;
            app.HzLabel_2 = uilabel(app.OtherFiltersPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.OtherFiltersPanel);
            app.HzLabel_3.Text = 'Hz';
            app.HzLabel_4 = uilabel(app.OtherFiltersPanel);
            app.HzLabel_4.Text = 'Hz';
            app.HzLabel_5 = uilabel(app.OtherFiltersPanel);
            app.HzLabel_5.Text = 'Hz';
            app.fRFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
            app.fRFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @fRFiltCheckBoxValueChanged, true);
            app.fRFiltCheckBox.Text = 'Fast Ripple';
            app.fRFiltLamp = uilamp(app.OtherFiltersPanel);
            app.HzLabel_14 = uilabel(app.OtherFiltersPanel);
            app.HzLabel_14.Text = 'Hz';
            app.gammaLim1Label_6 = uilabel(app.OtherFiltersPanel);
            app.gammaLim1Label_6.Enable = 'off';
            app.gammaLim1Label_6.Text = 'Lower:';
            app.fRLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.fRLim1Field.Enable = 'off';
            app.fRLim1Field.Value = 250;
            app.gammaLim2Label_6 = uilabel(app.OtherFiltersPanel);
            app.gammaLim2Label_6.Enable = 'off';
            app.gammaLim2Label_6.Text = 'Upper:';
            app.fRLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
            app.fRLim2Field.Enable = 'off';
            app.fRLim2Field.Value = 500;
            app.gammaLim1Label_3 = uilabel(app.LFPTab);
            app.gammaLim1Label_3.Text = 'Lower:';
            app.spectLim1Field = uieditfield(app.LFPTab, 'numeric');
            app.spectLim1Field.Value = 1;
            app.gammaLim2Label_3 = uilabel(app.LFPTab);
            app.gammaLim2Label_3.Text = 'Upper:';
            app.spectLim2Field = uieditfield(app.LFPTab, 'numeric');
            app.spectLim2Field.Value = 100;
            app.HzLabel_8 = uilabel(app.LFPTab);
            app.HzLabel_8.Text = 'Hz';
            app.swrDetectCheckBox = uicheckbox(app.LFPTab);
            app.swrDetectCheckBox.ValueChangedFcn = createCallbackFcn(app, @swrDetectCheckBoxValueChanged, true);
            app.swrDetectCheckBox.Text = 'SWR Detection';
            app.cellCheckBox = uicheckbox(app.LFPTab);
            app.cellCheckBox.ValueChangedFcn = createCallbackFcn(app, @cellCheckBoxValueChanged, true);
            app.cellCheckBox.Text = 'Include 2nd cell channel';
            app.cellCheckBox.Value = true;
            app.CellChannelLabel = uilabel(app.LFPTab);
            app.CellChannelLabel.Text = 'Cell Channel:';
            app.cellChannelField = uieditfield(app.LFPTab, 'numeric');
            app.cellChannelField.Value = 1;
            app.cellChannelLamp = uilamp(app.LFPTab);
            app.lfpChannelLamp = uilamp(app.LFPTab);
            app.SWRReviewPanel = uipanel(app.LFPTab);
            app.SWRReviewPanel.Title = 'SWR Review';
            app.rejectSWREvButton = uibutton(app.SWRReviewPanel, 'push');
            app.rejectSWREvButton.ButtonPushedFcn = createCallbackFcn(app, @rejectSWREvButtonPushed, true);
            app.rejectSWREvButton.Enable = 'off';
            app.rejectSWREvButton.Text = 'Reject Event';
            app.prevSWREvButton = uibutton(app.SWRReviewPanel, 'push');
            app.prevSWREvButton.ButtonPushedFcn = createCallbackFcn(app, @prevSWREvButtonPushed, true);
            app.prevSWREvButton.Enable = 'off';
            app.prevSWREvButton.Text = '<';
            app.nextSWREvButton = uibutton(app.SWRReviewPanel, 'push');
            app.nextSWREvButton.ButtonPushedFcn = createCallbackFcn(app, @nextSWREvButtonPushed, true);
            app.nextSWREvButton.Enable = 'off';
            app.nextSWREvButton.Text = '>';
            app.selSWREvLabel = uilabel(app.SWRReviewPanel);
            app.selSWREvLabel.Enable = 'off';
            app.selSWREvLabel.Text = '';
            app.textLabel = uilabel(app.SWRReviewPanel);
            app.textLabel.Enable = 'off';
            app.textLabel.Text = 'of';
            app.nSWREvLabel = uilabel(app.SWRReviewPanel);
            app.nSWREvLabel.Enable = 'off';
            app.nSWREvLabel.Text = '';
            app.notchFiltCheckBox = uicheckbox(app.LFPTab);
            app.notchFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @notchFiltCheckBoxValueChanged, true);
            app.notchFiltCheckBox.Text = 'Notch filter:';
            app.notchFiltFreqField = uieditfield(app.LFPTab, 'numeric');
            app.notchFiltFreqField.Editable = 'off';
            app.notchFiltFreqField.Value = 60;
            app.HzLabel_16 = uilabel(app.LFPTab);
            app.HzLabel_16.Text = 'Hz';
            app.importStimCheckBox = uicheckbox(app.LFPTab);
            app.importStimCheckBox.Text = 'Import Stimulation Peaks and LFP Responses';
            app.swrTypeButtonGroup = uibuttongroup(app.LFPTab);
            app.SWAndRippleButton = uiradiobutton(app.swrTypeButtonGroup);
            app.SWAndRippleButton.Text = 'Use overlap of SW and Ripple';
            app.SWAndRippleButton.Value = true;
            app.SWOnlyButton = uiradiobutton(app.swrTypeButtonGroup);
            app.SWOnlyButton.Text = 'SW Only';
            app.RippleOnlyButton = uiradiobutton(app.swrTypeButtonGroup);
            app.RippleOnlyButton.Text = 'Ripple Only';
            app.spkTab = uitab(app.TabGroup);
            app.spkTab.Title = 'Cell-Attached';
            app.fileNumSpkButtonGroup = uibuttongroup(app.spkTab);
            app.fileNumSpkButtonGroup.Title = 'Number of Recordings';
            app.SingleSpkButton = uiradiobutton(app.fileNumSpkButtonGroup);
            app.SingleSpkButton.Text = 'Single Recording';
            app.MultipleSpkButton = uiradiobutton(app.fileNumSpkButtonGroup);
            app.MultipleSpkButton.Text = 'Multiple (batch process)';
            app.MultipleSpkButton.Value = true;
            app.analyzeSpkButton = uibutton(app.spkTab, 'push');
            app.analyzeSpkButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeSpkButtonPushed, true);
            app.analyzeSpkButton.Text = 'Analyze Spikes';
            app.plotSpkButton = uibutton(app.spkTab, 'push');
            app.plotSpkButton.ButtonPushedFcn = createCallbackFcn(app, @plotSpkButtonPushed, true);
            app.plotSpkButton.Enable = 'off';
            app.plotSpkButton.Text = 'Plot Spike Analysis';
            app.importSpkCheckBox = uicheckbox(app.spkTab);
            app.importSpkCheckBox.Text = 'Import spike events exported from pClamp';
            app.importSpkCheckBox.Value = true;
            app.swrSpkButtonGroup = uibuttongroup(app.spkTab);
            app.useSWRDurationSpkButton = uiradiobutton(app.swrSpkButtonGroup);
            app.useSWRDurationSpkButton.Text = 'Use detected SWR duration';
            app.useSWRDurationSpkButton.Value = true;
            app.useSWRWindowSpkButton = uiradiobutton(app.swrSpkButtonGroup);
            app.useSWRWindowSpkButton.Text = 'Use standard swrWindow';
            app.calcEvMatrixSpkCheckBox = uicheckbox(app.swrSpkButtonGroup);
            app.calcEvMatrixSpkCheckBox.Enable = 'off';
            app.calcEvMatrixSpkCheckBox.Text = 'Calculate Event Matrix';
            app.calcEvMatrixSpkCheckBox.Value = true;
            app.parseSpkCheckBox = uicheckbox(app.swrSpkButtonGroup);
            app.parseSpkCheckBox.Enable = 'off';
            app.parseSpkCheckBox.Text = 'Parse spikes into SWR event-locked files';
            app.parseSpkCheckBox.Value = true;
            app.useSWRDurationSpkLamp = uilamp(app.swrSpkButtonGroup);
            app.useSWRWindowSpkLamp = uilamp(app.swrSpkButtonGroup);
            app.parseSpkLamp = uilamp(app.swrSpkButtonGroup);
            app.calcEvMatrixSpkLamp = uilamp(app.swrSpkButtonGroup);
            app.expSWRSpkCheckbox = uicheckbox(app.swrSpkButtonGroup);
            app.expSWRSpkCheckbox.Text = 'Export updated SWR Events';
            app.expSWRSpkCheckbox.Value = true;
            app.importSpkLamp = uilamp(app.spkTab);
            app.expSpkEvCheckBox = uicheckbox(app.spkTab);
            app.expSpkEvCheckBox.Text = 'Export Spike Events';
            app.expSpkEvCheckBox.Value = true;
            app.expBstEvCheckBox = uicheckbox(app.spkTab);
            app.expBstEvCheckBox.Text = 'Export Burst Events';
            app.reanalyzeSpkButton = uibutton(app.spkTab, 'push');
            app.reanalyzeSpkButton.ButtonPushedFcn = createCallbackFcn(app, @reanalyzeSpkButtonPushed, true);
            app.reanalyzeSpkButton.Text = 'Re-Analyze Spikes';
            app.CAAnalysisTypeButtonGroup = uibuttongroup(app.spkTab);
            app.CAAnalysisTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CAAnalysisTypeButtonGroupSelectionChanged, true);
            app.CAAnalysisTypeButtonGroup.Title = 'Type of Cell-LFP Analysis';
            app.swrSpkButton = uiradiobutton(app.CAAnalysisTypeButtonGroup);
            app.swrSpkButton.Text = 'Spike - SWR (previously detected events)';
            app.lfpSpkButton = uiradiobutton(app.CAAnalysisTypeButtonGroup);
            app.lfpSpkButton.Text = 'Spike - LFP (entire duration of file)';
            app.lfpSpkButton.Value = true;
            app.swrBstCheckBox = uicheckbox(app.CAAnalysisTypeButtonGroup);
            app.swrBstCheckBox.ValueChangedFcn = createCallbackFcn(app, @swrBstCheckBoxValueChanged, true);
            app.swrBstCheckBox.Enable = 'off';
            app.swrBstCheckBox.Text = 'Burst - SWR Analysis';
            app.swrBstLamp = uilamp(app.CAAnalysisTypeButtonGroup);
            app.swrSpkLamp = uilamp(app.CAAnalysisTypeButtonGroup);
            app.lfpSpkLamp = uilamp(app.CAAnalysisTypeButtonGroup);
            app.PSCTab = uitab(app.TabGroup);
            app.PSCTab.Title = 'Whole-Cell';
            app.fileNumPSCButtonGroup = uibuttongroup(app.PSCTab);
            app.fileNumPSCButtonGroup.Title = 'Number of Recordings';
            app.SinglePSCButton = uiradiobutton(app.fileNumPSCButtonGroup);
            app.SinglePSCButton.Text = 'Single Recording';
            app.MultiplePSCButton = uiradiobutton(app.fileNumPSCButtonGroup);
            app.MultiplePSCButton.Text = 'Multiple (batch process)';
            app.MultiplePSCButton.Value = true;
            app.analyzePSCButton = uibutton(app.PSCTab, 'push');
            app.analyzePSCButton.ButtonPushedFcn = createCallbackFcn(app, @analyzePSCButtonPushed, true);
            app.analyzePSCButton.Text = 'Analyze PSCs';
            app.plotPSCButton = uibutton(app.PSCTab, 'push');
            app.plotPSCButton.ButtonPushedFcn = createCallbackFcn(app, @plotPSCButtonPushed, true);
            app.plotPSCButton.Enable = 'off';
            app.plotPSCButton.Text = 'Plot PSC Analysis';
            app.importPSCCheckBox = uicheckbox(app.PSCTab);
            app.importPSCCheckBox.Text = 'Import PSC events exported from pClamp';
            app.importPSCCheckBox.Value = true;
            app.swrPSCCheckBox = uicheckbox(app.PSCTab);
            app.swrPSCCheckBox.ValueChangedFcn = createCallbackFcn(app, @swrPSCCheckBoxValueChanged, true);
            app.swrPSCCheckBox.Text = 'Calculate overlap of SWR and PSC events';
            app.swrPSCCheckBox.Value = true;
            app.swrPSCButtonGroup = uibuttongroup(app.PSCTab);
            app.useSWRDurationPSCButton = uiradiobutton(app.swrPSCButtonGroup);
            app.useSWRDurationPSCButton.Text = 'Use detected SWR duration';
            app.useSWRWindowPSCButton = uiradiobutton(app.swrPSCButtonGroup);
            app.useSWRWindowPSCButton.Text = 'Use standard swrWindow';
            app.useSWRWindowPSCButton.Value = true;
            app.calcEvMatrixPSCCheckBox = uicheckbox(app.swrPSCButtonGroup);
            app.calcEvMatrixPSCCheckBox.Enable = 'off';
            app.calcEvMatrixPSCCheckBox.Text = 'Calculate Event Matrix';
            app.calcEvMatrixPSCCheckBox.Value = true;
            app.parsePSCCheckBox = uicheckbox(app.swrPSCButtonGroup);
            app.parsePSCCheckBox.Enable = 'off';
            app.parsePSCCheckBox.Text = 'Parse PSCs into SWR event-locked files';
            app.parsePSCCheckBox.Value = true;
            app.useSWRDurationPSCLamp = uilamp(app.swrPSCButtonGroup);
            app.useSWRWindowPSCLamp = uilamp(app.swrPSCButtonGroup);
            app.parsePSCLamp = uilamp(app.swrPSCButtonGroup);
            app.calcEvMatrixPSCLamp = uilamp(app.swrPSCButtonGroup);
            app.expSWRPSCCheckbox = uicheckbox(app.swrPSCButtonGroup);
            app.expSWRPSCCheckbox.Text = 'Export updated SWR Events';
            app.expSWRPSCCheckbox.Value = true;
            app.importPSCLamp = uilamp(app.PSCTab);
            app.swrPSCLamp = uilamp(app.PSCTab);
            app.expPSCEvCheckBox = uicheckbox(app.PSCTab);
            app.expPSCEvCheckBox.Text = 'Export PSC Events';
            app.expPSCEvCheckBox.Value = true;
            app.reanalyzePSCButton = uibutton(app.PSCTab, 'push');
            app.reanalyzePSCButton.ButtonPushedFcn = createCallbackFcn(app, @reanalyzePSCButtonPushed, true);
            app.reanalyzePSCButton.Text = 'Re-Analyze PSCs';
            app.FiltersPSCPanel = uipanel(app.PSCTab);
            app.FiltersPSCPanel.Title = 'Cell Channel Filters';
            app.gammaFiltPSCCheckBox = uicheckbox(app.FiltersPSCPanel);
            app.gammaFiltPSCCheckBox.ValueChangedFcn = createCallbackFcn(app, @gammaFiltPSCCheckBoxValueChanged, true);
            app.gammaFiltPSCCheckBox.Text = 'Gamma Filter';
            app.gammaFiltPSCCheckBox.Value = true;
            app.rFiltPSCCheckBox = uicheckbox(app.FiltersPSCPanel);
            app.rFiltPSCCheckBox.ValueChangedFcn = createCallbackFcn(app, @rFiltPSCCheckBoxValueChanged, true);
            app.rFiltPSCCheckBox.Text = 'Ripple Filter';
            app.rFiltPSCCheckBox.Value = true;
            app.gammaFiltPSCLamp = uilamp(app.FiltersPSCPanel);
            app.rFiltPSCLamp = uilamp(app.FiltersPSCPanel);
            app.thetaLim1Label_3 = uilabel(app.FiltersPSCPanel);
            app.thetaLim1Label_3.Text = 'Lower:';
            app.gammaLim1PSCField = uieditfield(app.FiltersPSCPanel, 'numeric');
            app.gammaLim1PSCField.Value = 20;
            app.thetaLim2Label_3 = uilabel(app.FiltersPSCPanel);
            app.thetaLim2Label_3.Text = 'Upper:';
            app.gammaLim2PSCField = uieditfield(app.FiltersPSCPanel, 'numeric');
            app.gammaLim2PSCField.Value = 50;
            app.gammaLim1Label_4 = uilabel(app.FiltersPSCPanel);
            app.gammaLim1Label_4.Text = 'Lower:';
            app.rLim1PSCField = uieditfield(app.FiltersPSCPanel, 'numeric');
            app.rLim1PSCField.Value = 120;
            app.gammaLim2Label_4 = uilabel(app.FiltersPSCPanel);
            app.gammaLim2Label_4.Text = 'Upper:';
            app.rLim2PSCField = uieditfield(app.FiltersPSCPanel, 'numeric');
            app.rLim2PSCField.Value = 220;
            app.HzLabel_10 = uilabel(app.FiltersPSCPanel);
            app.HzLabel_10.Text = 'Hz';
            app.HzLabel_12 = uilabel(app.FiltersPSCPanel);
            app.HzLabel_12.Text = 'Hz';
            app.spectPSCCheckBox = uicheckbox(app.PSCTab);
            app.spectPSCCheckBox.ValueChangedFcn = createCallbackFcn(app, @spectPSCCheckBoxValueChanged, true);
            app.spectPSCCheckBox.Text = 'Spectral Analysis';
            app.spectPSCCheckBox.Value = true;
            app.spectPSCLamp = uilamp(app.PSCTab);
            app.HzLabel_15 = uilabel(app.PSCTab);
            app.HzLabel_15.Text = 'Hz';
            app.gammaLim1Label_7 = uilabel(app.PSCTab);
            app.gammaLim1Label_7.Text = 'Lower:';
            app.spectLim1PSCField = uieditfield(app.PSCTab, 'numeric');
            app.spectLim1PSCField.Value = 1;
            app.gammaLim2Label_7 = uilabel(app.PSCTab);
            app.gammaLim2Label_7.Text = 'Upper:';
            app.spectLim2PSCField = uieditfield(app.PSCTab, 'numeric');
            app.spectLim2PSCField.Value = 500;
            app.pscEventPolarityButtonGroup = uibuttongroup(app.PSCTab);
            app.pscPosPolarityButton = uiradiobutton(app.pscEventPolarityButtonGroup);
            app.pscPosPolarityButton.Text = '+';
            app.pscNegPolarityButton = uiradiobutton(app.pscEventPolarityButtonGroup);
            app.pscNegPolarityButton.Text = '-';
            app.pscNegPolarityButton.Value = true;
            app.PSCPolarityLabel = uilabel(app.PSCTab);
            app.PSCPolarityLabel.Text = 'PSC Polarity:';
            app.swrPSQCheckBox = uicheckbox(app.PSCTab);
            app.swrPSQCheckBox.Text = 'Calculate peri-SWR Charge';
            app.swrPSQCheckBox.Value = true;
            app.CaTab = uitab(app.TabGroup);
            app.CaTab.Title = 'Calcium Imaging';
            app.fileNumCaButtonGroup = uibuttongroup(app.CaTab);
            app.fileNumCaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @fileNumCaButtonGroupSelectionChanged, true);
            app.fileNumCaButtonGroup.Title = 'Number of Recordings';
            app.SingleButtonCa = uiradiobutton(app.fileNumCaButtonGroup);
            app.SingleButtonCa.Text = 'Single Recording';
            app.MultipleButtonCa = uiradiobutton(app.fileNumCaButtonGroup);
            app.MultipleButtonCa.Text = 'Multiple (batch process)';
            app.MultipleButtonCa.Value = true;
            app.SDThresholdLabel = uilabel(app.CaTab);
            app.SDThresholdLabel.Text = 'SD Threshold:';
            app.sdMultCaField = uieditfield(app.CaTab, 'numeric');
            app.sdMultCaField.Value = 4;
            app.SamplingIntervalLabel = uilabel(app.CaTab);
            app.SamplingIntervalLabel.Text = 'Sampling Interval:';
            app.samplingIntCaField = uieditfield(app.CaTab, 'numeric');
            app.samplingIntCaField.Value = 0.5;
            app.msLabel = uilabel(app.CaTab);
            app.msLabel.Text = 'ms';
            app.analyzeCaButton = uibutton(app.CaTab, 'push');
            app.analyzeCaButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeCaButtonPushed, true);
            app.analyzeCaButton.Text = 'Analyze Calcium';
            app.reanalyzeCaButton = uibutton(app.CaTab, 'push');
            app.reanalyzeCaButton.ButtonPushedFcn = createCallbackFcn(app, @reanalyzeCaButtonPushed, true);
            app.reanalyzeCaButton.Text = 'Re-Analyze Calcium';
            app.SDLabel_2 = uilabel(app.CaTab);
            app.SDLabel_2.Text = 'SD';
            app.expCaCheckbox = uicheckbox(app.CaTab);
            app.expCaCheckbox.Text = 'Export Ca Events';
            app.expCaCheckbox.Value = true;
            app.interpCheckbox = uicheckbox(app.CaTab);
            app.interpCheckbox.ValueChangedFcn = createCallbackFcn(app, @interpCheckboxValueChanged, true);
            app.interpCheckbox.Text = 'Interpolate Data';
            app.interpCheckbox.Value = true;
            app.peakDetectCaCheckbox = uicheckbox(app.CaTab);
            app.peakDetectCaCheckbox.ValueChangedFcn = createCallbackFcn(app, @peakDetectCaCheckboxValueChanged, true);
            app.peakDetectCaCheckbox.Text = 'Spontaneous Peak Detection';
            app.peakDetectCaCheckbox.Value = true;
            app.interpCaLamp = uilamp(app.CaTab);
            app.peakDetectCaLamp = uilamp(app.CaTab);
            app.plotCaButton = uibutton(app.CaTab, 'push');
            app.plotCaButton.ButtonPushedFcn = createCallbackFcn(app, @plotCaButtonPushed, true);
            app.plotCaButton.Enable = 'off';
            app.plotCaButton.Text = 'Plot Ca Analysis';
            app.swrCaButtonGroup = uibuttongroup(app.CaTab);
            app.swrCaButtonGroup.Title = ' ';
            app.useSWRDurationCaButton = uiradiobutton(app.swrCaButtonGroup);
            app.useSWRDurationCaButton.Text = 'Use detected SWR duration';
            app.useSWRDurationCaButton.Value = true;
            app.useSWRWindowCaButton = uiradiobutton(app.swrCaButtonGroup);
            app.useSWRWindowCaButton.Text = 'Use standard swrWindow';
            app.expSWRCaCheckbox = uicheckbox(app.swrCaButtonGroup);
            app.expSWRCaCheckbox.Text = 'Export updated SWR events';
            app.expSWRCaCheckbox.Value = true;
            app.consistentThreshCheckBox = uicheckbox(app.CaTab);
            app.consistentThreshCheckBox.Text = 'Consistent Threshold for Batch';
            app.baseDetectCaButtonGroup = uibuttongroup(app.CaTab);
            app.baseDetectCaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @baseDetectCaButtonGroupSelectionChanged, true);
            app.baseDetectCaButtonGroup.Title = ' ';
            app.baseDetectCaButton1 = uiradiobutton(app.baseDetectCaButtonGroup);
            app.baseDetectCaButton1.Text = 'Lower Quantile';
            app.baseDetectCaButton2 = uiradiobutton(app.baseDetectCaButtonGroup);
            app.baseDetectCaButton2.Text = 'Gaussian Fit';
            app.baseDetectCaButton2.Value = true;
            app.baseQuantCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
            app.baseQuantCaField.ValueDisplayFormat = '%.2f';
            app.baseQuantCaField.Enable = 'off';
            app.baseQuantCaField.Value = 0.8;
            app.MinDiffbetweenPeaksLabel = uilabel(app.baseDetectCaButtonGroup);
            app.MinDiffbetweenPeaksLabel.Text = 'Min Diff. between Peaks:  ';
            app.pkDiffMinField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
            app.pkDiffMinField.Value = 0.1;
            app.FFLabel = uilabel(app.baseDetectCaButtonGroup);
            app.FFLabel.Text = 'ΔF/F';
            app.PeakSimilarityLimitLabel = uilabel(app.baseDetectCaButtonGroup);
            app.PeakSimilarityLimitLabel.Text = 'Peak Similarity Limit:  ';
            app.pkSimLimField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
            app.pkSimLimField.Value = 2;
            app.KurtosisMinLabel = uilabel(app.baseDetectCaButtonGroup);
            app.KurtosisMinLabel.Text = 'Kurtosis Min:';
            app.kurtosisMinField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
            app.KurtosisMaxLabel = uilabel(app.baseDetectCaButtonGroup);
            app.KurtosisMaxLabel.Text = 'Kurtosis Max:';
            app.kurtosisMaxField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
            app.kurtosisMaxField.Value = 5;
            app.ExclusionQuantileLabel = uilabel(app.baseDetectCaButtonGroup);
            app.ExclusionQuantileLabel.Text = 'Exclusion Quantile:';
            app.excludeQuantField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
            app.excludeQuantField.Value = 0.95;
            app.plotFitHistoCheckBox = uicheckbox(app.baseDetectCaButtonGroup);
            app.plotFitHistoCheckBox.Text = 'plot Fit Histograms for each cell';
            app.baseCorrectCaButtonGroup = uibuttongroup(app.CaTab);
            app.baseCorrectCaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @baseCorrectCaButtonGroupSelectionChanged, true);
            app.baseCorrectCaButtonGroup.Title = ' ';
            app.baseCorrectCaButton1 = uiradiobutton(app.baseCorrectCaButtonGroup);
            app.baseCorrectCaButton1.Text = 'Gaussian Filter';
            app.baseCorrectCaButton2 = uiradiobutton(app.baseCorrectCaButtonGroup);
            app.baseCorrectCaButton2.Text = 'Smoothed Average';
            app.baseCorrectCaButton2.Value = true;
            app.CaLim1Field = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
            app.CaLim1Field.Enable = 'off';
            app.CaLim1Field.Value = 0.03;
            app.CaLim2Field = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
            app.CaLim2Field.Enable = 'off';
            app.CaLim2Field.Value = 4;
            app.HzLabel_9 = uilabel(app.baseCorrectCaButtonGroup);
            app.HzLabel_9.Text = 'Hz';
            app.toLabel = uilabel(app.baseCorrectCaButtonGroup);
            app.toLabel.Text = 'to';
            app.FilterOrderLabel = uilabel(app.baseCorrectCaButtonGroup);
            app.FilterOrderLabel.Text = 'Filter Order:';
            app.CaFiltOrderField = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
            app.CaFiltOrderField.ValueDisplayFormat = '%.0f';
            app.CaFiltOrderField.Enable = 'off';
            app.CaFiltOrderField.Value = 80;
            app.FilterAlphaLabel = uilabel(app.baseCorrectCaButtonGroup);
            app.FilterAlphaLabel.Text = 'Filter Alpha:';
            app.CaFiltAlphaField = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
            app.CaFiltAlphaField.Enable = 'off';
            app.CaFiltAlphaField.Value = 2.5;
            app.SmoothFactorLabel = uilabel(app.baseCorrectCaButtonGroup);
            app.SmoothFactorLabel.Text = 'Smooth Factor:';
            app.smoothFactorField = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
            app.smoothFactorField.Value = 0.25;
            app.SkipdetectionforfirstLabel = uilabel(app.CaTab);
            app.SkipdetectionforfirstLabel.Text = 'Skip detection for first:';
            app.skipDetectLimField = uieditfield(app.CaTab, 'numeric');
            app.skipDetectLimField.Value = 1;
            app.sLabel = uilabel(app.CaTab);
            app.sLabel.Text = 's';
            app.baseDetectCaCheckbox = uicheckbox(app.CaTab);
            app.baseDetectCaCheckbox.ValueChangedFcn = createCallbackFcn(app, @baseDetectCaCheckboxValueChanged, true);
            app.baseDetectCaCheckbox.Text = 'Baseline Detection';
            app.baseDetectCaCheckbox.Value = true;
            app.baseCorrectCaLamp = uilamp(app.CaTab);
            app.baseDetectCaLamp = uilamp(app.CaTab);
            app.stimCaPanel = uipanel(app.CaTab);
            app.stimCaPanel.Title = 'SWR Detection';
            app.msafterstimulationpeakLabel = uilabel(app.stimCaPanel);
            app.msafterstimulationpeakLabel.Text = 'ms after stimulation peak';
            app.fromLabel = uilabel(app.stimCaPanel);
            app.fromLabel.Text = 'from';
            app.stimCaLim1Field = uieditfield(app.stimCaPanel, 'numeric');
            app.stimCaLim1Field.Enable = 'off';
            app.toLabel_2 = uilabel(app.stimCaPanel);
            app.toLabel_2.Text = 'to';
            app.stimCaLim2Field = uieditfield(app.stimCaPanel, 'numeric');
            app.stimCaLim2Field.Enable = 'off';
            app.stimCaLim2Field.Value = 1000;
            app.expStimCaCheckbox = uicheckbox(app.stimCaPanel);
            app.expStimCaCheckbox.Enable = 'off';
            app.expStimCaCheckbox.Text = 'Export stimulation events';
            app.swrCaCheckbox = uicheckbox(app.CaTab);
            app.swrCaCheckbox.ValueChangedFcn = createCallbackFcn(app, @swrCaCheckboxValueChanged, true);
            app.swrCaCheckbox.Text = 'Calculate overlap of SWR and calcium events';
            app.swrCaCheckbox.Value = true;
            app.swrCaLamp = uilamp(app.CaTab);
            app.stimCaCheckBox = uicheckbox(app.CaTab);
            app.stimCaCheckBox.ValueChangedFcn = createCallbackFcn(app, @stimCaCheckBoxValueChanged, true);
            app.stimCaCheckBox.Text = 'Calculate ΔF/F in response to stimulation';
            app.sdBaseFactorLabel = uilabel(app.CaTab);
            app.sdBaseFactorLabel.Text = 'sdBaseFactor';
            app.sdBaseFactorCaField = uieditfield(app.CaTab, 'numeric');
            app.sdBaseFactorCaField.Value = 0.75;
            app.alignOptionGroup = uibuttongroup(app.CaTab);
            app.alignOptionGroup.Title = 'Align pClamp/dFoF files from:';
            app.alignOptionStartButton = uiradiobutton(app.alignOptionGroup);
            app.alignOptionStartButton.Text = 'Start';
            app.alignOptionStartButton.Value = true;
            app.alignOptionEndButton = uiradiobutton(app.alignOptionGroup);
            app.alignOptionEndButton.Text = 'End';
            app.baseCorrectCaCheckbox = uicheckbox(app.CaTab);
            app.baseCorrectCaCheckbox.ValueChangedFcn = createCallbackFcn(app, @baseCorrectCaCheckboxValueChanged, true);
            app.baseCorrectCaCheckbox.Text = 'Baseline Correction';
            app.baseCorrectCaCheckbox.Value = true;
            app.cellTypePanel = uipanel(app.CaTab);
            app.cellTypePanel.Title = ' ';
            app.Type1EditFieldLabel = uilabel(app.cellTypePanel);
            app.Type1EditFieldLabel.Text = 'Type 1:';
            app.cellType1EditField = uieditfield(app.cellTypePanel, 'text');
            app.cellType1EditField.Enable = 'off';
            app.cellType1EditField.Value = 'Deep';
            app.Type2EditFieldLabel = uilabel(app.cellTypePanel);
            app.Type2EditFieldLabel.Text = 'Type 2:';
            app.cellType2EditField = uieditfield(app.cellTypePanel, 'text');
            app.cellType2EditField.Enable = 'off';
            app.cellType2EditField.Value = 'Supe';
            app.cellTypeOptionCheckbox = uicheckbox(app.CaTab);
            app.cellTypeOptionCheckbox.ValueChangedFcn = createCallbackFcn(app, @cellTypeOptionCheckboxValueChanged, true);
            app.cellTypeOptionCheckbox.Text = 'Cell Type Option';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.resultTree = uitree(app.ResultsTab);
            app.resultTree.SelectionChangedFcn = createCallbackFcn(app, @resultTreeSelectionChanged, true);
            app.TypeLabel = uilabel(app.ResultsTab);
            app.TypeLabel.Text = 'Type:';
            app.varTypeTextArea = uitextarea(app.ResultsTab);
            app.varTypeTextArea.Editable = 'off';
            app.varValueTable = uitable(app.ResultsTab);
            app.varValueTable.ColumnName = '';
            app.varValueTable.RowName = {};
            app.exportEventsButton = uibutton(app.ResultsTab, 'push');
            app.exportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @exportEventsButtonPushed, true);
            app.exportEventsButton.Enable = 'off';
            app.exportEventsButton.Text = 'Export Events';
            app.dataFile1Loaded = uilabel(app.swrAnalysisUIFigure);
            app.dataFile1Loaded.Text = '';
            app.dataFile2Loaded = uilabel(app.swrAnalysisUIFigure);
            app.dataFile2Loaded.Text = '';
            app.openFile2Button = uibutton(app.swrAnalysisUIFigure, 'push');
            app.openFile2Button.ButtonPushedFcn = createCallbackFcn(app, @openFile2ButtonPushed, true);
            app.openFile2Button.Enable = 'off';
            app.openFile2Button.Text = 'Open File 2';
            app.closeFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
            app.closeFileButton.ButtonPushedFcn = createCallbackFcn(app, @closeFileButtonPushed, true);
            app.closeFileButton.Enable = 'off';
            app.closeFileButton.Text = 'Close File';
            app.openFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
            app.openFileButton.ButtonPushedFcn = createCallbackFcn(app, @openFileButtonPushed, true);
            app.openFileButton.Text = 'Open Analyzed File';
            app.openPrevFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
            app.openPrevFileButton.ButtonPushedFcn = createCallbackFcn(app, @openPrevFileButtonPushed, true);
            app.openPrevFileButton.Enable = 'off';
            app.openPrevFileButton.Text = '<';
            app.openNextFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
            app.openNextFileButton.ButtonPushedFcn = createCallbackFcn(app, @openNextFileButtonPushed, true);
            app.openNextFileButton.Enable = 'off';
            app.openNextFileButton.Text = '>';
            app.saveFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
            app.saveFileButton.ButtonPushedFcn = createCallbackFcn(app, @saveFileButtonPushed, true);
            app.saveFileButton.Enable = 'off';
            app.saveFileButton.Text = 'Save File';
            app.openFile3Button = uibutton(app.swrAnalysisUIFigure, 'push');
            app.openFile3Button.ButtonPushedFcn = createCallbackFcn(app, @openFile3ButtonPushed, true);
            app.openFile3Button.Enable = 'off';
            app.openFile3Button.Text = 'Open File 3';
            app.dataFile3Loaded = uilabel(app.swrAnalysisUIFigure);
            app.dataFile3Loaded.Text = '';
            % Show the figure after all components are created
            app.swrAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = swrAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.swrAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.swrAnalysisUIFigure)
        end
    end
end